classdef AS_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        Untitled_1      matlab.ui.container.Menu
        IR2             matlab.ui.control.Button
        Prequalization  matlab.ui.control.CheckBox
        Convlin         matlab.ui.control.Button
        Help            matlab.ui.control.Button
        Cancel          matlab.ui.control.Button
        pushbutton7     matlab.ui.control.Button
        pushbutton8     matlab.ui.control.Button
        pushbutton9     matlab.ui.control.Button
        pushbutton10    matlab.ui.control.Button
        pushbutton11    matlab.ui.control.Button
        Image           matlab.ui.control.Image
    end

    
    methods (Access = private)
        function Plotfreqresponse_Callback(app, hObject, eventdata, handles)
            plotspectlogf_main;
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function AS_OpeningFcn(app)
%             % Choose default command line output for AS
%             app.output = hObject;
%             currentdirectory= pwd;
%             [root_path,~]=fileparts(currentdirectory);
%             root_path = strcat(root_path,filesep);
%             assignin('base','root_path',root_path);
%             % Update handles structure
%             guidata(hObject, handles);
            global preequal;
            preequal=0;
            assignin ('base', 'preequal', preequal);
            global UpdatedApp;
        end

        % Button pushed function: Cancel
        function Cancel_Callback(app, event)
            close(app.figure1);
        end

        % Button pushed function: Convlin
        function Convlin_Callback(app, event)
            close(app.figure1);
            assignin ('base', 'elevation', 0);
            assignin('base','angle',0);
            Transform_App;
        end

        % Button pushed function: Help
        function Help_Callback(app, event)
            open('AcousticSuiteUserGuide.pdf');
        end

        % Button pushed function: IR2
        function IR_Callback(app, event)
            SineSweepMethod_App;
            close(app.figure1);
        end

        % Value changed function: Prequalization
        function Prequalization_Callback(app, event)
            global preequal;
            if (Prequalization.Value == 1)
            	preequal=1;
            else
            	preequal=0;
            end
            assignin ('base', 'preequal', preequal);
        end

        % Button pushed function: pushbutton7
        function saved_IR_Callback(app, event)
            wavprint('main');
        end

        % Button pushed function: pushbutton11
        function saved_RT60_Callback(app, event)
            RT60('main');
        end

        % Button pushed function: pushbutton10
        function saved_a_weight_Callback(app, event)
            plot_a_weight('main');
        end

        % Button pushed function: pushbutton8
        function saved_distortion_Callback(app, event)
            plotdist('main');
        end

        % Button pushed function: pushbutton9
        function saved_waterfall_Callback(app, event)
            plotwaterfall('main');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'IE';
            app.Untitled_1 = uimenu(app.figure1);
            app.Untitled_1.Text = 'Main Menu';
            app.IR2 = uibutton(app.figure1, 'push');
            app.IR2.ButtonPushedFcn = createCallbackFcn(app, @IR_Callback, true);
            app.IR2.Text = 'OBTAIN IMPULSE RESPONSE';
            app.Prequalization = uicheckbox(app.figure1);
            app.Prequalization.ValueChangedFcn = createCallbackFcn(app, @Prequalization_Callback, true);
            app.Prequalization.Text = 'PREQUALIZATION';
            app.Prequalization.Value = true;
            app.Convlin = uibutton(app.figure1, 'push');
            app.Convlin.ButtonPushedFcn = createCallbackFcn(app, @Convlin_Callback, true);
            app.Convlin.Text = 'TRANSFORM YOUR MUSIC';
            app.Help = uibutton(app.figure1, 'push');
            app.Help.ButtonPushedFcn = createCallbackFcn(app, @Help_Callback, true);
            app.Help.Text = 'H E L P';
            app.Cancel = uibutton(app.figure1, 'push');
            app.Cancel.ButtonPushedFcn = createCallbackFcn(app, @Cancel_Callback, true);
            app.Cancel.Text = 'C A N C E L';
            app.pushbutton7 = uibutton(app.figure1, 'push');
            app.pushbutton7.ButtonPushedFcn = createCallbackFcn(app, @saved_IR_Callback, true);
            app.pushbutton7.Text = {'PLOT SAVED IMPULSE RESPONSE'; 'RESPONSE'};
            app.pushbutton8 = uibutton(app.figure1, 'push');
            app.pushbutton8.ButtonPushedFcn = createCallbackFcn(app, @saved_distortion_Callback, true);
            app.pushbutton8.Text = {'PLOT SAVED DISTORTION DATA'; 'RESPONSE'};
            app.pushbutton9 = uibutton(app.figure1, 'push');
            app.pushbutton9.ButtonPushedFcn = createCallbackFcn(app, @saved_waterfall_Callback, true);
            app.pushbutton9.Text = {'PLOT SAVED WATERFALL'; 'RESPONSE'};
            app.pushbutton10 = uibutton(app.figure1, 'push');
            app.pushbutton10.ButtonPushedFcn = createCallbackFcn(app, @saved_a_weight_Callback, true);
            app.pushbutton10.Text = {'A-WEIGHTING'; 'RESPONSE'};
            app.pushbutton11 = uibutton(app.figure1, 'push');
            app.pushbutton11.ButtonPushedFcn = createCallbackFcn(app, @saved_RT60_Callback, true);
            app.pushbutton11.Text = {'PLOT RT60'; 'RESPONSE'};
            app.Image = uiimage(app.figure1);
            app.Image.ImageSource = 'main.png';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AS_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @AS_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end