classdef DIsplayIR_app_stereo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Phase                      matlab.ui.control.UIAxes
        GroupDelay                 matlab.ui.control.UIAxes
        PlotDistorsionDataButton   matlab.ui.control.Button
        PlotImpulseResponseButton  matlab.ui.control.Button
        PlotWaterfallButton        matlab.ui.control.Button
        PlotRT60Button             matlab.ui.control.Button
        CancelButton               matlab.ui.control.Button
        Freq                       matlab.ui.control.UIAxes
        Phase_2                    matlab.ui.control.UIAxes
        GroupDelay_2               matlab.ui.control.UIAxes
        Freq_2                     matlab.ui.control.UIAxes
        Harmonic2Button            matlab.ui.control.Button
        Harmonic3Button            matlab.ui.control.Button
        Harmonic4Button            matlab.ui.control.Button
        THDButton                  matlab.ui.control.Button
        THDnoiseButton             matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        SmoothingDropDown          matlab.ui.control.DropDown
        DisplayAmpmetricCheckBox   matlab.ui.control.CheckBox
        DisplayGDmetricCheckBox    matlab.ui.control.CheckBox
        AmpmetricDispLeft          matlab.ui.control.EditField
        GDmetricDispLeft           matlab.ui.control.EditField
        GDmetricDispRight          matlab.ui.control.EditField
        AmpmetricDispRight         matlab.ui.control.EditField
        Image                      matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Distortion curves
            global h2l
            global h2r
            global h3l
            global h3r
            global h4l
            global h4r
            global thd_noise_l
            global thd_noise_r
            global thd_l
            global thd_r
            global unsmooth_faxis
            global ft_wins_hann1
            global ft_wins_hann2
            global THD2
            global THD1
            global ft_wins_hann_noise1
            global ft_wins_hann_noise2
            
            % Smoothed magnitude curves
            global Oct_mag_left
            global Bark_mag_left
            global True_oct_mag_left
            global Oct_mag_right
            global Bark_mag_right
            global True_oct_mag_right
            
            % AQM curves and variables
            global GD_upper_left
            global GD_upper_right
            global Blauert_left
            global Blauert_right
            global Amp_upper_left
            global Amp_upper_right
            global Amp_lower_left
            global Amp_lower_right
            
            % Frequency data
            freq = evalin('base','freq');
            freq_bark = evalin('base','freq_bark');
            fcenter10 = evalin('base','fcenter10');
            trueOct_f = evalin('base','trueOct_f');
            
            % Left & Right magnitude, phase, groud delay data from base
            % workspace
            group_delay_left = evalin('base','group_delay_left');
            group_delay_bark_left = evalin('base','group_delay_bark_left');
            myphase_left = evalin('base','myphase_left');
            phase_bark_left = evalin('base','phase_bark_left');
            db_left = evalin('base','db_left');
            mag_bark_left = evalin('base','mag_bark_left');
            k10_left = evalin('base','k10_left'); % 1/3 Base 10 Oct Smooth
            trueOct_left = evalin('base','trueOct_left'); % True Base 10 Octave Smooth
            
            group_delay_right = evalin('base','group_delay_right');
            group_delay_bark_right = evalin('base','group_delay_bark_right');
            myphase_right = evalin('base','myphase_right');
            phase_bark_right = evalin('base','phase_bark_right');
            db_right = evalin('base','db_right');
            mag_bark_right = evalin('base','mag_bark_right');
            k10_right = evalin('base','k10_right');
            trueOct_right = evalin('base','trueOct_right');
            
            smoothing = evalin('base', 'smoothing');
            
            % Group Delay metric calculation
%             gd_aqm_app_left = GDAQM(freq_bark, group_delay_bark_left);
%             gd_aqm_app_right = GDAQM(freq_bark, group_delay_bark_right);
            gd_aqm_app_left = GD_AQM(group_delay_bark_left,freq_bark);
            gd_aqm_app_right = GD_AQM(group_delay_bark_right,freq_bark);
            
            % Amplitude metric calculation
%             amp_aqm_app_left = AmpAQM(0,0,16, db_left, freq);
%             amp_aqm_app_right = AmpAQM(0,0,16, db_right, freq);
            amp_aqm_app_left = AmpAQM_v2(db_left, freq);
            amp_aqm_app_right = AmpAQM_v2(db_right, freq);
            
            % Amplitude metric curves variables
            amp_metric_freqUp = evalin('base', 'amp_metric_freqUp');
            amp_metric_freqLow = evalin('base', 'amp_metric_freqLow');
            lowerTol = evalin('base', 'lowerTol');
            upperTol = evalin('base', 'upperTol');
            Amp_ideal = zeros(length(freq_bark),1);
                        
            % Magnitude plot - left channel
            hold(app.Freq,'on')
            plot(app.Freq, freq, db_left, 'DisplayName', 'Fundamental','LineWidth',0.25,'Color', [0.85 0.85 0.85]);
            plot(app.Freq,freq_bark, Amp_ideal,'LineWidth',0.75, 'DisplayName','Desired amplitude');
            % Amplitude metric curves
            Amp_upper_left = plot(app.Freq,amp_metric_freqUp, upperTol,'LineWidth',0.75, 'DisplayName','Upper tolerance');
            set(Amp_upper_left,'Visible','off')
            Amp_lower_left = plot(app.Freq,amp_metric_freqLow, lowerTol,'LineWidth',0.75, 'DisplayName','Lower tolerance');
            set(Amp_lower_left,'Visible','off')
            % Smoothed magnityude curves
            Bark_mag_left = plot(app.Freq, freq_bark, mag_bark_left, 'DisplayName', 'Fundamental-Bark','LineWidth',0.65,'color',[0, 0.4470, 0.7410],'HandleVisibility','off');
            Oct_mag_left = plot(app.Freq, fcenter10, k10_left, 'DisplayName', 'Fundamental-1/3 Octave','LineWidth',0.65,'color',[0, 0.4470, 0.7410],'HandleVisibility','off');
            True_oct_mag_left = plot(app.Freq, trueOct_f, trueOct_left, 'DisplayName', 'Fundamental-Octave','LineWidth',0.65,'color',[0, 0.4470, 0.7410],'HandleVisibility','off');
            if smoothing == 1
                set(Bark_mag_left, 'Visible', 'on')
                set(Oct_mag_left,'Visible','off')
                set(True_oct_mag_left, 'Visible', 'off')
            elseif smoothing == 2
                set(Oct_mag_left,'Visible','on')
                set(Bark_mag_left, 'Visible', 'off')
                set(True_oct_mag_left, 'Visible', 'off')
            elseif smoothing == 3
                set(True_oct_mag_left,'Visible','on')
                set(Bark_mag_left, 'Visible', 'off')
                set(Oct_mag_left, 'Visible', 'off')
            end
            hold off
            
            Harmonics_app_stereo_test();
            
            unsmooth_faxis = evalin('base','unsmooth_faxis');
            ft_wins_hann1 = evalin('base','ft_wins_hann1'); % left channel
            ft_wins_hann2 = evalin('base','ft_wins_hann2'); % right channel
            THD1 = evalin('base','THD1'); % left channel
            THD2 = evalin('base','THD2'); % right channel
            ft_wins_hann_noise1 = evalin('base','ft_wins_hann_noise1');% left channel
            ft_wins_hann_noise2 = evalin('base','ft_wins_hann_noise2');% right channel
            
            % Harmonics - left channel
            h2l = plot(app.Freq,unsmooth_faxis, ft_wins_hann1{2}','LineWidth',0.75,'color', [0.4940, 0.1840, 0.5560],'HandleVisibility','off');%'DisplayName','Harmonic 2', 'color', [0.4940, 0.1840, 0.5560]);
            set(h2l,'Visible','off')
            h3l = plot(app.Freq,unsmooth_faxis, ft_wins_hann1{3}','LineWidth',0.75,'color', [0.9290, 0.6940, 0.1250],'HandleVisibility','off');%'DisplayName','Harmonic 3', 'color', [0.9290, 0.6940, 0.1250]);
            set(h3l,'Visible','off')
            h4l = plot(app.Freq,unsmooth_faxis, ft_wins_hann1{4}','LineWidth',0.75,'color', [0.4660, 0.6740, 0.1880],'HandleVisibility','off');%'DisplayName','Harmonic 4', 'color', [0.4660, 0.6740, 0.1880]);
            set(h4l,'Visible','off')
            thd_noise_l = plot(app.Freq,unsmooth_faxis, ft_wins_hann_noise1','LineWidth',0.75,'color', [0.6350, 0.0780, 0.1840],'HandleVisibility','off');%'DisplayName','THD+noise', 'color', [0.6350, 0.0780, 0.1840]);
            set(thd_noise_l,'Visible','off')
            thd_l = plot(app.Freq, THD1','LineWidth',0.75, 'color',[0.75, 0.75, 0],'HandleVisibility','off');%'DisplayName','THD', 'color',[0.75, 0.75, 0]);
            set(thd_l,'Visible','off')
            hold(app.Freq,'off')

            % Magnitude plot - right channel
            hold(app.Freq_2,'on')
            plot(app.Freq_2, freq, db_right, 'DisplayName', 'Fundamental','LineWidth',0.25,'Color', [0.85 0.85 0.85]);
            % Amplitude metric curves
            plot(app.Freq_2,freq_bark, Amp_ideal,'LineWidth',0.75, 'DisplayName','Desired amplitude');
            Amp_upper_right = plot(app.Freq_2,amp_metric_freqUp, upperTol,'LineWidth',0.75, 'DisplayName','Upper tolerance');
            set(Amp_upper_right,'Visible','off')
            Amp_lower_right = plot(app.Freq_2,amp_metric_freqLow, lowerTol,'LineWidth',0.75, 'DisplayName','Lower tolerance');
            set(Amp_lower_right,'Visible','off')
            % Smoothed magnitude curves
            Bark_mag_right = plot(app.Freq_2, freq_bark, mag_bark_right, 'LineWidth',0.65,'color',[0, 0.4470, 0.7410],'HandleVisibility','off');%'DisplayName', 'Fundamental-Bark',
            Oct_mag_right = plot(app.Freq_2, fcenter10, k10_right,'LineWidth',0.65,'color',[0, 0.4470, 0.7410],'HandleVisibility','off');% 'DisplayName', 'Fundamental-1/3 Octave',
            True_oct_mag_right = plot(app.Freq_2, trueOct_f, trueOct_right,'LineWidth',0.65,'color',[0, 0.4470, 0.7410],'HandleVisibility','off');% 'DisplayName', 'Fundamental-Octave',
            if smoothing == 1
                set(Bark_mag_right, 'Visible', 'on')
                set(Oct_mag_right,'Visible','off')
                set(True_oct_mag_right,'Visible','off')
            elseif smoothing == 2
                set(Oct_mag_right,'Visible','on')
                set(Bark_mag_right, 'Visible', 'off')
                set(True_oct_mag_right,'Visible','off')
            elseif smoothing == 3
                set(True_oct_mag_right,'Visible','on')
                set(Bark_mag_right, 'Visible', 'off')
                set(Oct_mag_right,'Visible','off')
            end
            
            % Harmonics - right channel
            h2r = plot(app.Freq_2,unsmooth_faxis, ft_wins_hann2{2}','LineWidth',0.75, 'color', [0.4940, 0.1840, 0.5560]);%,'DisplayName','Harmonic 2'
            set(h2r,'Visible','off')
            h3r = plot(app.Freq_2,unsmooth_faxis, ft_wins_hann2{3}','LineWidth',0.75, 'color', [0.9290, 0.6940, 0.1250]);%,'DisplayName','Harmonic 3'
            set(h3r,'Visible','off')
            h4r = plot(app.Freq_2,unsmooth_faxis, ft_wins_hann2{4}','LineWidth',0.75, 'color', [0.4660, 0.6740, 0.1880]);%,'DisplayName','Harmonic 4'
            set(h4r,'Visible','off')
            thd_noise_r = plot(app.Freq_2,unsmooth_faxis, ft_wins_hann_noise2','LineWidth',0.75, 'color', [0.6350, 0.0780, 0.1840]);%,'DisplayName','THD+noise'
            set(thd_noise_r,'Visible','off')
            thd_r = plot(app.Freq_2, THD2','LineWidth',0.75, 'color',[0.75, 0.75, 0]);%,'DisplayName','THD'
            set(thd_r,'Visible','off')
            hold(app.Freq_2,'off')
            
            % Phase plot
            hold(app.Phase,'on')
            plot(app.Phase, freq, myphase_left);
            plot(app.Phase, freq_bark, phase_bark_left);
            hold(app.Phase,'off')
            
            hold(app.Phase_2,'on')
            plot(app.Phase_2, freq, myphase_right);
            plot(app.Phase_2, freq_bark, phase_bark_right);
            hold(app.Phase_2,'off')
            
            % Group Delay plot
            % Setting the Group Delay metric curves as invisible
            GD_upper_limit = 80*ones(length(freq_bark),1);
            GD_lower_limit = zeros(length(freq_bark),1);
            blauert_app = evalin('base', 'blauert_app');
            blauert_freq_app = evalin('base', 'blauert_freq_app');
                        
            hold(app.GroupDelay,'on')
            plot(app.GroupDelay, freq, group_delay_left,'DisplayName', 'Group Delay','LineWidth',0.25,'Color', [0.85 0.85 0.85]);
            plot(app.GroupDelay, freq_bark, group_delay_bark_left, 'DisplayName', 'Group Delay-Bark','LineWidth',0.65);
            Blauert_left = plot(app.GroupDelay,blauert_freq_app, blauert_app,'LineWidth',0.75, 'DisplayName','Blauert curve');
            set(Blauert_left,'Visible','off')            
            GD_upper_left = plot(app.GroupDelay,freq_bark, GD_upper_limit,'LineWidth',0.75, 'DisplayName','Upper Tolerance');
            set(GD_upper_left,'Visible','off')            
            plot(app.GroupDelay,freq_bark, GD_lower_limit,'LineWidth',0.75, 'DisplayName','Desired Group Delay');
            hold(app.GroupDelay,'off')
            
            hold(app.GroupDelay_2,'on')
            plot(app.GroupDelay_2, freq, group_delay_right,'LineWidth',0.25,'Color', [0.85 0.85 0.85]);
            plot(app.GroupDelay_2, freq_bark, group_delay_bark_right,'LineWidth',0.65);
            Blauert_right = plot(app.GroupDelay_2,blauert_freq_app, blauert_app,'LineWidth',0.75, 'DisplayName','Blauert curve');
            set(Blauert_right,'Visible','off')            
            GD_upper_right = plot(app.GroupDelay_2,freq_bark, GD_upper_limit,'LineWidth',0.75, 'DisplayName','Upper Tolerance');
            set(GD_upper_right,'Visible','off')            
            plot(app.GroupDelay_2,freq_bark, GD_lower_limit,'LineWidth',0.75, 'DisplayName','Desired Group Delay');
            hold(app.GroupDelay_2,'off')
            
            % Display metrics by default
            app.GDmetricDispLeft.Value = ['Left ',num2str(gd_aqm_app_left),'/10'];
            app.GDmetricDispRight.Value = ['Right ',num2str(gd_aqm_app_right),'/10'];
            app.AmpmetricDispLeft.Value = ['Left ',num2str(amp_aqm_app_left),'/10'];
            app.AmpmetricDispRight.Value = ['Right ',num2str(amp_aqm_app_right),'/10'];
            
            legend(app.Freq,'show','location','best')
%             legend(app.Freq,'Location','northeastoutside')
            legend(app.GroupDelay,'show','location','best')
%             legend(app.GroupDelay,'Location','northeastoutside')
            
%             legend(app.Freq_2,'show','location','best')
        end

        % Button pushed function: PlotImpulseResponseButton
        function PlotImpulseResponseButtonPushed(app, event)
            wavprint('plotfig');
        end

        % Button pushed function: PlotDistorsionDataButton
        function PlotDistorsionDataButtonPushed(app, event)
            plotdist('plotfig');
        end

        % Button pushed function: PlotWaterfallButton
        function PlotWaterfallButtonPushed(app, event)
            plotwaterfall('plotfig');
        end

        % Button pushed function: PlotRT60Button
        function PlotRT60ButtonPushed(app, event)
            RT60('plotfig');
        end

        % Callback function
        function AWeightingButtonPushed(app, event)
            plot_a_weight('plotfig');
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            global h2l
            global h2r
            global h3l
            global h3r
            global h4l
            global h4r
            global thd_noise_l
            global thd_noise_r
            global thd_l
            global thd_r
            
            set(thd_noise_l,'Visible','off')
            set(thd_noise_r,'Visible','off')
            set(thd_l,'Visible','off')
            set(thd_r,'Visible','off')
            set(h4l,'Visible','off')
            set(h4r,'Visible','off')
            set(h3l,'Visible','off')
            set(h3r,'Visible','off')
            set(h2l,'Visible','off')
            set(h2r,'Visible','off')
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            close(app.UIFigure);
            close all;
            AS_App;
        end

        % Button pushed function: Harmonic2Button
        function Harmonic2ButtonPushed(app, event)
            global h2l
            global h2r
            set(h2l,'Visible','on')
            set(h2r,'Visible','on')
        end

        % Button pushed function: Harmonic3Button
        function Harmonic3ButtonPushed(app, event)
            global h3l
            global h3r
            set(h3l,'Visible','on')
            set(h3r,'Visible','on')
        end

        % Button pushed function: Harmonic4Button
        function Harmonic4ButtonPushed(app, event)
            global h4l
            global h4r
            set(h4l,'Visible','on')
            set(h4r,'Visible','on')
        end

        % Button pushed function: THDButton
        function THDButtonPushed(app, event)
            global thd_l
            global thd_r
            set(thd_l,'Visible','on')
            set(thd_r,'Visible','on')
        end

        % Button pushed function: THDnoiseButton
        function THDnoiseButtonPushed(app, event)
            global thd_noise_l
            global thd_noise_r
            set(thd_noise_l,'Visible','on')
            set(thd_noise_r,'Visible','on')
        end

        % Value changed function: SmoothingDropDown
        function SmoothingDropDownValueChanged(app, event)
            global Oct_mag_left
            global Oct_mag_right
            global Bark_mag_left
            global Bark_mag_right
            global True_oct_mag_right
            global True_oct_mag_left
            
            smoothing = app.SmoothingDropDown.Value;
            
            if strcmp(smoothing,'Bark')
                set(Bark_mag_left, 'Visible', 'on')
                set(Bark_mag_right, 'Visible', 'on')
                set(Oct_mag_right, 'Visible', 'off')
                set(Oct_mag_left, 'Visible', 'off')
                set(True_oct_mag_right, 'Visible', 'off')
                set(True_oct_mag_left, 'Visible', 'off')
            elseif strcmp(smoothing,'1/3 Octave Base 10')
                set(Oct_mag_left, 'Visible', 'on')
                set(Oct_mag_right, 'Visible', 'on')
                set(Bark_mag_right, 'Visible', 'off')
                set(Bark_mag_left, 'Visible', 'off')
                set(True_oct_mag_right, 'Visible', 'off')
                set(True_oct_mag_left, 'Visible', 'off')
            else
                set(True_oct_mag_right, 'Visible', 'on')
                set(True_oct_mag_left, 'Visible', 'on')
                set(Oct_mag_left, 'Visible', 'off')
                set(Oct_mag_right, 'Visible', 'off')
                set(Bark_mag_left, 'Visible', 'off')
                set(Bark_mag_right, 'Visible', 'off')
            end
        end

        % Value changed function: DisplayGDmetricCheckBox
        function DisplayGDmetricCheckBoxValueChanged(app, event)
            global GD_upper_right
            global GD_upper_left
            global Blauert_left
            global Blauert_right
            
            value = app.DisplayGDmetricCheckBox.Value;
            
            if value == 1
                set(Blauert_left, 'Visible', 'on')
                set(Blauert_right, 'Visible', 'on')
                set(GD_upper_right, 'Visible', 'on')
                set(GD_upper_left, 'Visible', 'on')
            else
                set(Blauert_left, 'Visible', 'off')
                set(Blauert_right, 'Visible', 'off')
                set(GD_upper_right, 'Visible', 'off')
                set(GD_upper_left, 'Visible', 'off')
            end
        end

        % Value changed function: DisplayAmpmetricCheckBox
        function DisplayAmpmetricCheckBoxValueChanged(app, event)
            global Amp_upper_right
            global Amp_upper_left
            global Amp_lower_right
            global Amp_lower_left
            
            value = app.DisplayAmpmetricCheckBox.Value;
            
            if value == 1
                set(Amp_upper_right, 'Visible', 'on')
                set(Amp_upper_left, 'Visible', 'on')
                set(Amp_lower_right, 'Visible', 'on')
                set(Amp_lower_left, 'Visible', 'on')
            else
                set(Amp_upper_right, 'Visible', 'off')
                set(Amp_upper_left, 'Visible', 'off')
                set(Amp_lower_right, 'Visible', 'off')
                set(Amp_lower_left, 'Visible', 'off')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Phase = uiaxes(app.UIFigure);
            title(app.Phase, 'Phase Response')
            xlabel(app.Phase, 'Frequency (Hz)')
            ylabel(app.Phase, 'Phase (Degrees)')
            app.Phase.XLim = [10 20000];
            app.Phase.YLim = [-500000 500000];
            app.Phase.XScale = 'log';
            app.GroupDelay = uiaxes(app.UIFigure);
            title(app.GroupDelay, 'Group Delay')
            xlabel(app.GroupDelay, 'Frequency (Hz)')
            ylabel(app.GroupDelay, 'Group Delay (ms)')
            app.GroupDelay.XLim = [10 20000];
            app.GroupDelay.YLim = [0 100];
            app.GroupDelay.XScale = 'log';
            app.PlotDistorsionDataButton = uibutton(app.UIFigure, 'push');
            app.PlotDistorsionDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDistorsionDataButtonPushed, true);
            app.PlotDistorsionDataButton.Text = 'Plot Distorsion Data';
            app.PlotImpulseResponseButton = uibutton(app.UIFigure, 'push');
            app.PlotImpulseResponseButton.ButtonPushedFcn = createCallbackFcn(app, @PlotImpulseResponseButtonPushed, true);
            app.PlotImpulseResponseButton.Text = 'Plot Impulse Response';
            app.PlotWaterfallButton = uibutton(app.UIFigure, 'push');
            app.PlotWaterfallButton.ButtonPushedFcn = createCallbackFcn(app, @PlotWaterfallButtonPushed, true);
            app.PlotWaterfallButton.Text = 'Plot Waterfall';
            app.PlotRT60Button = uibutton(app.UIFigure, 'push');
            app.PlotRT60Button.ButtonPushedFcn = createCallbackFcn(app, @PlotRT60ButtonPushed, true);
            app.PlotRT60Button.Text = 'Plot RT60';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Freq = uiaxes(app.UIFigure);
            title(app.Freq, 'RTF Left Channel')
            xlabel(app.Freq, 'Frequency (Hz)')
            ylabel(app.Freq, 'Amplitude (dB)')
            app.Freq.XLim = [10 20000];
            app.Freq.YLim = [-50 20];
            app.Freq.XScale = 'log';
            app.Phase_2 = uiaxes(app.UIFigure);
            title(app.Phase_2, 'Phase Response')
            xlabel(app.Phase_2, 'Frequency (Hz)')
            ylabel(app.Phase_2, 'Phase (Degrees)')
            app.Phase_2.XLim = [10 20000];
            app.Phase_2.YLim = [-500000 500000];
            app.Phase_2.XScale = 'log';
            app.GroupDelay_2 = uiaxes(app.UIFigure);
            title(app.GroupDelay_2, 'Group Delay')
            xlabel(app.GroupDelay_2, 'Frequency (Hz)')
            ylabel(app.GroupDelay_2, 'Group Delay (ms)')
            app.GroupDelay_2.XLim = [10 20000];
            app.GroupDelay_2.YLim = [0 100];
            app.GroupDelay_2.XScale = 'log';
            app.Freq_2 = uiaxes(app.UIFigure);
            title(app.Freq_2, 'RTF Right Channel')
            xlabel(app.Freq_2, 'Frequency (Hz)')
            ylabel(app.Freq_2, 'Amplitude (dB)')
            app.Freq_2.XLim = [10 20000];
            app.Freq_2.YLim = [-50 20];
            app.Freq_2.XScale = 'log';
            app.Harmonic2Button = uibutton(app.UIFigure, 'push');
            app.Harmonic2Button.ButtonPushedFcn = createCallbackFcn(app, @Harmonic2ButtonPushed, true);
            app.Harmonic2Button.Icon = 'purple.png';
            app.Harmonic2Button.Text = 'Harmonic 2';
            app.Harmonic3Button = uibutton(app.UIFigure, 'push');
            app.Harmonic3Button.ButtonPushedFcn = createCallbackFcn(app, @Harmonic3ButtonPushed, true);
            app.Harmonic3Button.Icon = 'yellow.png';
            app.Harmonic3Button.Text = 'Harmonic 3';
            app.Harmonic4Button = uibutton(app.UIFigure, 'push');
            app.Harmonic4Button.ButtonPushedFcn = createCallbackFcn(app, @Harmonic4ButtonPushed, true);
            app.Harmonic4Button.Icon = 'green.png';
            app.Harmonic4Button.Text = 'Harmonic 4';
            app.THDButton = uibutton(app.UIFigure, 'push');
            app.THDButton.ButtonPushedFcn = createCallbackFcn(app, @THDButtonPushed, true);
            app.THDButton.Icon = 'green2.png';
            app.THDButton.Text = 'THD';
            app.THDnoiseButton = uibutton(app.UIFigure, 'push');
            app.THDnoiseButton.ButtonPushedFcn = createCallbackFcn(app, @THDnoiseButtonPushed, true);
            app.THDnoiseButton.Icon = 'maroon.png';
            app.THDnoiseButton.Text = 'THD + noise';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SmoothingDropDown = uidropdown(app.UIFigure);
            app.SmoothingDropDown.Items = {'Bark', '1/3 Octave Base 10', 'Octave Base 10', ''};
            app.SmoothingDropDown.ValueChangedFcn = createCallbackFcn(app, @SmoothingDropDownValueChanged, true);
            app.SmoothingDropDown.Value = 'Bark';
            app.DisplayAmpmetricCheckBox = uicheckbox(app.UIFigure);
            app.DisplayAmpmetricCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayAmpmetricCheckBoxValueChanged, true);
            app.DisplayAmpmetricCheckBox.Text = 'Display Amp metric';
            app.DisplayGDmetricCheckBox = uicheckbox(app.UIFigure);
            app.DisplayGDmetricCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayGDmetricCheckBoxValueChanged, true);
            app.DisplayGDmetricCheckBox.Text = 'Display GD metric';
            app.AmpmetricDispLeft = uieditfield(app.UIFigure, 'text');
            app.AmpmetricDispLeft.Editable = 'off';
            app.GDmetricDispLeft = uieditfield(app.UIFigure, 'text');
            app.GDmetricDispLeft.Editable = 'off';
            app.GDmetricDispRight = uieditfield(app.UIFigure, 'text');
            app.GDmetricDispRight.Editable = 'off';
            app.AmpmetricDispRight = uieditfield(app.UIFigure, 'text');
            app.AmpmetricDispRight.Editable = 'off';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'blue_main.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DIsplayIR_app_stereo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end