classdef DisplayIR_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Phase                      matlab.ui.control.UIAxes
        GroupDelay                 matlab.ui.control.UIAxes
        PlotDistortionDataButton   matlab.ui.control.Button
        PlotImpulseResponseButton  matlab.ui.control.Button
        PlotWaterfallButton        matlab.ui.control.Button
        PlotRT60Button             matlab.ui.control.Button
        CancelButton               matlab.ui.control.Button
        Freq                       matlab.ui.control.UIAxes
        Harmonic2Button            matlab.ui.control.Button
        Harmonic3Button            matlab.ui.control.Button
        Harmonic4Button            matlab.ui.control.Button
        THDnoiseButton             matlab.ui.control.Button
        THDButton                  matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        GDmetricCheck              matlab.ui.control.CheckBox
        GDmetricDisp               matlab.ui.control.EditField
        AMPmetricCheck             matlab.ui.control.CheckBox
        AMPmetricDisp              matlab.ui.control.EditField
        SmoothingDropDownLabel     matlab.ui.control.Label
        SmoothingDropDown          matlab.ui.control.DropDown
        Image                      matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Smoothing options
            global Oct_mag
            global Bark_mag
            global True_oct_mag
            
            % Harmonics variables
            global ft_wins_hann
            global ft_wins_hann_noise
            global THD
            global unsmooth_faxis;
            global h2
            global h3 
            global h4  
            global THD_noise
            global THD_curve
            
            % Metric variables
            global gd_aqm_app
            global GD_upper
            global GD_lower
            global Blauert
            global amp_aqm_app
            global Amp_upper
            global Amp_lower
            global Amp_ideal
            
            % Fetching magnitude, phase, and group delay data from base
            % workspace
            mOrs = evalin('base','mOrs');
            freq = evalin('base','freq');
            freq_bark = evalin('base','freq_bark');
            trueOct_f = evalin('base','trueOct_f');
            fcenter10 = evalin('base', 'fcenter10');
            smoothing = evalin('base', 'smoothing');
            
            if strcmp(mOrs,'mono-left')
                group_delay = evalin('base','group_delay_left');
                group_delay_bark = evalin('base','group_delay_bark_left');
                myphase = evalin('base','myphase_left');
                phase_bark = evalin('base','phase_bark_left');
                db = evalin('base','db_left');
                mag_bark = evalin('base','mag_bark_left');
                trueOct = evalin('base','trueOct_left');
                k10 = evalin('base', 'k10_left');
            else
                freq = evalin('base','freq');
                group_delay = evalin('base','group_delay_right');
                freq_bark = evalin('base','freq_bark');
                group_delay_bark = evalin('base','group_delay_bark_right');
                myphase = evalin('base','myphase_right');
                phase_bark = evalin('base','phase_bark_right');
                db = evalin('base','db_right');
                mag_bark = evalin('base','mag_bark_right');
                trueOct = evalin('base','trueOct_right');
                k10 = evalin('base', 'k10_right');
            end
            
            % Getting Harmonic distortion data            
            Harmonics('plotfig');
            
            ft_wins_hann = evalin('base','ft_wins_hann');            
            ft_wins_hann_noise = evalin('base','ft_wins_hann_noise');            
            THD = evalin('base','THD');            
            unsmooth_faxis = evalin('base','unsmooth_faxis');
            
            % Plotting the magnitude, phase, and group delay
            hold (app.Freq,'on')
            plot(app.Freq, freq, db, 'DisplayName', 'Fundamental','LineWidth',0.25,'Color', [0.85 0.85 0.85]);
            % Bark Smoothing
            Bark_mag = plot(app.Freq, freq_bark, mag_bark,'LineWidth',0.65,'color',[0, 0.4470, 0.7410],'HandleVisibility','off');
            % 1/3 Base 10 Octave Smoothing
            Oct_mag = plot(app.Freq, fcenter10, k10,'LineWidth',0.65,'color',[0, 0.4470, 0.7410],'HandleVisibility','off');
            % True Base 10 Octave Smoothing
            True_oct_mag = plot(app.Freq, trueOct_f, trueOct,'LineWidth',0.65,'color',[0, 0.4470, 0.7410],'HandleVisibility','off');
            if smoothing == 1
                set(Bark_mag, 'Visible', 'on')
                set(Oct_mag,'Visible','off')
                set(True_oct_mag, 'Visible', 'off')
            elseif smoothing == 2
                set(Oct_mag,'Visible','on')
                set(Bark_mag, 'Visible', 'off')
                set(True_oct_mag, 'Visible', 'off')
            elseif smoothing == 3
                set(True_oct_mag,'Visible','on')
                set(Bark_mag, 'Visible', 'off')
                set(Oct_mag, 'Visible', 'off')
            end
            hold off
            
            hold (app.Phase,'on')
            plot(app.Phase, freq, myphase);
            plot(app.Phase, freq_bark, phase_bark);
            hold off
            
            hold (app.GroupDelay,'on')
            plot(app.GroupDelay, freq, group_delay, 'DisplayName', 'Group Delay','LineWidth',0.25,'Color', [0.85 0.85 0.85]);
            plot(app.GroupDelay, freq_bark, group_delay_bark, 'DisplayName', 'Group Delay-Bark','LineWidth',0.65);
            hold off
                        
            % Making the Harmonics and THD curves invisible
            h2 = plot(app.Freq,unsmooth_faxis, ft_wins_hann{2},'LineWidth',0.65,'color', [0.4940, 0.1840, 0.5560],'HandleVisibility','off');%'DisplayName','Harmonic 2');
            set(h2,'Visible','off')
                    
            h3 = plot(app.Freq,unsmooth_faxis, ft_wins_hann{3},'LineWidth',0.65,'color', [0.9290, 0.6940, 0.1250],'HandleVisibility','off');%'DisplayName','Harmonic 3');
            set(h3,'Visible','off')
                    
            h4 = plot(app.Freq,unsmooth_faxis, ft_wins_hann{4},'LineWidth',0.65,'color', [0.4660, 0.6740, 0.1880],'HandleVisibility','off');%,'DisplayName','Harmonic 4');
            set(h4,'Visible','off')
            
            THD_noise = plot(app.Freq,unsmooth_faxis, ft_wins_hann_noise,'LineWidth',0.65,'color', [0.6350, 0.0780, 0.1840],'HandleVisibility','off');% 'DisplayName','THD+noise');
            set(THD_noise,'Visible','off')
            
            THD_curve = plot(app.Freq,THD,'LineWidth',0.65, 'color',[0.75, 0.75, 0],'HandleVisibility','off');% 'DisplayName','THD');
            set(THD_curve,'Visible','off')
            
            % Group Delay metric calculation
%             gd_aqm_app = GDAQM(freq_bark, group_delay_bark);
            gd_aqm_app = GD_AQM(group_delay_bark,freq_bark);
            
            % Making the Group Delay metric curves invisible
            blauert_app = evalin('base', 'blauert_app');
            blauert_freq_app = evalin('base', 'blauert_freq_app');
            
            Blauert = plot(app.GroupDelay,blauert_freq_app, blauert_app,'LineWidth',0.75, 'DisplayName','Blauert curve');
            set(Blauert,'Visible','off')
            
            GD_upper_limit = 80*ones(length(freq_bark),1);
            GD_lower_limit = zeros(length(freq_bark),1);
            
            GD_upper = plot(app.GroupDelay,freq_bark, GD_upper_limit,'LineWidth',0.75, 'DisplayName','Upper Tolerance');
            set(GD_upper,'Visible','off')
            GD_lower = plot(app.GroupDelay,freq_bark, GD_lower_limit,'LineWidth',0.75, 'DisplayName','Desired Group Delay');
%             set(GD_lower,'Visible','on')
            
            % Amplitude metric calculation
%             amp_aqm_app = AmpAQM(0,0,16, db, freq);
            amp_aqm_app = AmpAQM_v2(db, freq);
            
            % Making the Amplitude metric curves invisible
            amp_metric_freqUp = evalin('base', 'amp_metric_freqUp');
            amp_metric_freqLow = evalin('base', 'amp_metric_freqLow');
            lowerTol = evalin('base', 'lowerTol');
            upperTol = evalin('base', 'upperTol');
            Amp_ideal = zeros(length(freq_bark),1);
            
            Amp_upper = plot(app.Freq,amp_metric_freqUp, upperTol,'LineWidth',0.75, 'DisplayName','Upper tolerance');
            set(Amp_upper,'Visible','off')
            Amp_lower = plot(app.Freq,amp_metric_freqLow, lowerTol,'LineWidth',0.75, 'DisplayName','Lower tolerance');
            set(Amp_lower,'Visible','off')
            Amp_ideal = plot(app.Freq,freq_bark, Amp_ideal,'LineWidth',0.75, 'DisplayName','Desired amplitude');
%             set(Amp_ideal,'Visible','off')
            
            % Display metrics by default
            app.AMPmetricDisp.Value = [num2str(amp_aqm_app),'/10'];
            app.GDmetricDisp.Value = [num2str(gd_aqm_app),'/10'];
            
            % Display smoothing value by default
            app.SmoothingDropDown.Value = ['Bark'];

            legend(app.Freq,'show')
            legend(app.Freq,'Location','northeastoutside')
            legend(app.GroupDelay,'show')
            legend(app.GroupDelay,'Location','northeastoutside')
        end

        % Button pushed function: PlotImpulseResponseButton
        function PlotImpulseResponseButtonPushed(app, event)
            wavprint('plotfig');
        end

        % Button pushed function: PlotDistortionDataButton
        function PlotDistortionDataButtonPushed(app, event)
            plotdist('plotfig');
        end

        % Button pushed function: PlotWaterfallButton
        function PlotWaterfallButtonPushed(app, event)
            plotwaterfall('plotfig');
        end

        % Button pushed function: PlotRT60Button
        function PlotRT60ButtonPushed(app, event)
            RT60('plotfig');
        end

        % Callback function
        function AWeightingButtonPushed(app, event)
            plot_a_weight('plotfig');
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            close(app.UIFigure);
            close all;
            AS_App;
        end

        % Button pushed function: Harmonic2Button
        function Harmonic2ButtonPushed(app, event)
            global h2            
            set(h2,'Visible','on')
        end

        % Button pushed function: Harmonic3Button
        function Harmonic3ButtonPushed(app, event)
            global h3            
            set(h3,'Visible','on')
        end

        % Button pushed function: Harmonic4Button
        function Harmonic4ButtonPushed(app, event)
            global h4
            set(h4, 'Visible', 'on')
        end

        % Button pushed function: THDnoiseButton
        function THDnoiseButtonPushed(app, event)
            global THD_noise
            set(THD_noise, 'Visible', 'on')
        end

        % Button pushed function: THDButton
        function THDButtonPushed(app, event)
            global THD_curve          
            set(THD_curve, 'Visible', 'on')
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            global h2
            global h3
            global h4
            global THD_noise
            global THD_curve
            
            set(h2,'Visible','off')
            set(h3,'Visible','off')
            set(h4,'Visible','off')
            set(THD_noise,'Visible','off')
            set(THD_curve,'Visible','off')
        end

        % Value changed function: GDmetricCheck
        function GDmetricCheckValueChanged(app, event)
            global GD_upper
%             global GD_lower
            global Blauert
            
            value = app.GDmetricCheck.Value;
            
            if value == 1
                set(Blauert, 'Visible', 'on')
%                 set(GD_lower, 'Visible', 'on')
                set(GD_upper, 'Visible', 'on')
            else
                set(Blauert, 'Visible', 'off')
%                 set(GD_lower, 'Visible', 'off')
                set(GD_upper, 'Visible', 'off')
            end
        end

        % Value changed function: AMPmetricCheck
        function AMPmetricCheckValueChanged(app, event)
            global Amp_upper
            global Amp_lower
%             global Amp_ideal
            
            value = app.AMPmetricCheck.Value;
            
            if value == 1
                set(Amp_upper, 'Visible', 'on')
                set(Amp_lower, 'Visible', 'on')
%                 set(Amp_ideal, 'Visible', 'on')
            else
                set(Amp_upper, 'Visible', 'off')
                set(Amp_lower, 'Visible', 'off')
%                 set(Amp_ideal, 'Visible', 'off')
            end
        end

        % Value changed function: SmoothingDropDown
        function SmoothingDropDownValueChanged(app, event)
            global Oct_mag
            global Bark_mag
            global True_oct_mag
            
            smoothing = app.SmoothingDropDown.Value;
            
            if strcmp(smoothing,'1/3 Octave Base 10')
                set(Oct_mag, 'Visible', 'on')
                set(Bark_mag, 'Visible', 'off')
                set(True_oct_mag, 'Visible', 'off')
            elseif strcmp(smoothing,'Bark')
                set(Oct_mag, 'Visible', 'off')
                set(Bark_mag, 'Visible', 'on')
                set(True_oct_mag, 'Visible', 'off')
            else
                set(Oct_mag, 'Visible', 'off')
                set(Bark_mag, 'Visible', 'off')
                set(True_oct_mag, 'Visible', 'on')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Phase = uiaxes(app.UIFigure);
            title(app.Phase, 'Phase Response')
            xlabel(app.Phase, 'Frequency (Hz)')
            ylabel(app.Phase, 'Phase (Degrees)')
            app.Phase.XLim = [10 20000];
            app.Phase.YLim = [-500000 500000];
            app.Phase.XScale = 'log';
            app.GroupDelay = uiaxes(app.UIFigure);
            title(app.GroupDelay, 'Group Delay')
            xlabel(app.GroupDelay, 'Frequency (Hz)')
            ylabel(app.GroupDelay, 'Group Delay (ms)')
            app.GroupDelay.XLim = [10 20000];
            app.GroupDelay.YLim = [0 100];
            app.GroupDelay.XScale = 'log';
            app.PlotDistortionDataButton = uibutton(app.UIFigure, 'push');
            app.PlotDistortionDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDistortionDataButtonPushed, true);
            app.PlotDistortionDataButton.Text = 'Plot Distorsion Data';
            app.PlotImpulseResponseButton = uibutton(app.UIFigure, 'push');
            app.PlotImpulseResponseButton.ButtonPushedFcn = createCallbackFcn(app, @PlotImpulseResponseButtonPushed, true);
            app.PlotImpulseResponseButton.Text = 'Plot Impulse Response';
            app.PlotWaterfallButton = uibutton(app.UIFigure, 'push');
            app.PlotWaterfallButton.ButtonPushedFcn = createCallbackFcn(app, @PlotWaterfallButtonPushed, true);
            app.PlotWaterfallButton.Text = 'Plot Waterfall';
            app.PlotRT60Button = uibutton(app.UIFigure, 'push');
            app.PlotRT60Button.ButtonPushedFcn = createCallbackFcn(app, @PlotRT60ButtonPushed, true);
            app.PlotRT60Button.Text = 'Plot RT60';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Freq = uiaxes(app.UIFigure);
            title(app.Freq, 'Frequency Response')
            xlabel(app.Freq, 'Frequency (Hz)')
            ylabel(app.Freq, 'Amplitude (dB)')
            app.Freq.XLim = [10 20000];
            app.Freq.YLim = [-50 20];
            app.Freq.XScale = 'log';
            app.Harmonic2Button = uibutton(app.UIFigure, 'push');
            app.Harmonic2Button.ButtonPushedFcn = createCallbackFcn(app, @Harmonic2ButtonPushed, true);
            app.Harmonic2Button.Icon = 'purple.png';
            app.Harmonic2Button.Text = 'Harmonic 2';
            app.Harmonic3Button = uibutton(app.UIFigure, 'push');
            app.Harmonic3Button.ButtonPushedFcn = createCallbackFcn(app, @Harmonic3ButtonPushed, true);
            app.Harmonic3Button.Icon = 'yellow.png';
            app.Harmonic3Button.Text = 'Harmonic 3';
            app.Harmonic4Button = uibutton(app.UIFigure, 'push');
            app.Harmonic4Button.ButtonPushedFcn = createCallbackFcn(app, @Harmonic4ButtonPushed, true);
            app.Harmonic4Button.Icon = 'green.png';
            app.Harmonic4Button.Text = 'Harmonic 4';
            app.THDnoiseButton = uibutton(app.UIFigure, 'push');
            app.THDnoiseButton.ButtonPushedFcn = createCallbackFcn(app, @THDnoiseButtonPushed, true);
            app.THDnoiseButton.Icon = 'maroon.png';
            app.THDnoiseButton.Text = 'THD+noise';
            app.THDButton = uibutton(app.UIFigure, 'push');
            app.THDButton.ButtonPushedFcn = createCallbackFcn(app, @THDButtonPushed, true);
            app.THDButton.Icon = 'green2.png';
            app.THDButton.Text = 'THD';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GDmetricCheck = uicheckbox(app.UIFigure);
            app.GDmetricCheck.ValueChangedFcn = createCallbackFcn(app, @GDmetricCheckValueChanged, true);
            app.GDmetricCheck.Text = 'Display GD metric';
            app.GDmetricDisp = uieditfield(app.UIFigure, 'text');
            app.GDmetricDisp.Editable = 'off';
            app.AMPmetricCheck = uicheckbox(app.UIFigure);
            app.AMPmetricCheck.ValueChangedFcn = createCallbackFcn(app, @AMPmetricCheckValueChanged, true);
            app.AMPmetricCheck.Text = 'Display Amp metric';
            app.AMPmetricDisp = uieditfield(app.UIFigure, 'text');
            app.AMPmetricDisp.Editable = 'off';
            app.SmoothingDropDownLabel = uilabel(app.UIFigure);
            app.SmoothingDropDownLabel.Text = 'Smoothing';
            app.SmoothingDropDown = uidropdown(app.UIFigure);
            app.SmoothingDropDown.Items = {'Bark', '1/3 Octave Base 10', 'Octave Base 10'};
            app.SmoothingDropDown.ValueChangedFcn = createCallbackFcn(app, @SmoothingDropDownValueChanged, true);
            app.SmoothingDropDown.Value = 'Bark';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'blue_main.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DisplayIR_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end