classdef RT60_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        RT60          matlab.ui.control.UIAxes
        BarkButton    matlab.ui.control.Button
        OctaveButton  matlab.ui.control.Button
        ResetButton   matlab.ui.control.Button
        CancelButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global EDT
            global EDT_bark
            global EDT_oct
            global T20
            global T20_bark
            global T20_oct
            global T30
            global T30_bark
            global T30_oct
            global Filter
            global freq_out
            global fcenter
            RT60('main');
            Filter = [63 125 250 500 1000 2000 4000 8000];
            EDT = evalin('base', 'EDT');
            EDT_bark = evalin('base', 'EDT_bark');
            EDT_oct = evalin('base', 'EDT_oct');
            T20 = evalin('base', 'T20');
            T20_bark = evalin('base', 'T20_bark');
            T20_oct = evalin('base', 'T20_oct');
            T30 = evalin('base', 'T30');
            T30_bark = evalin('base', 'T30_bark');
            T30_oct = evalin('base', 'T30_oct');
            freq_out = evalin('base', 'freq_out');
            fcenter = evalin('base', 'fcenter');
            
            hold(app.RT60,'on')
            plot(app.RT60, Filter, EDT, 'DisplayName', 'EDT original');
            plot(app.RT60, Filter, T20, 'DisplayName', 'T20 original');
            plot(app.RT60, Filter, T30, 'DisplayName', 'T30 original');
            hold(app.RT60,'off')
            legend(app.RT60,'show','location','best')
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            close(app.UIFigure);
            close all;
            AS_App;
        end

        % Button pushed function: BarkButton
        function BarkButtonPushed(app, event)
            global EDT_bark
            global T20_bark
            global T30_bark
            global freq_out
            cla(app.RT60,'reset')
            hold(app.RT60,'on')
            plot(app.RT60, freq_out, EDT_bark, 'DisplayName', 'EDT Bark');
            plot(app.RT60, freq_out, T20_bark, 'DisplayName', 'T20 Bark');
            plot(app.RT60, freq_out, T30_bark, 'DisplayName', 'T30 Bark');
            hold(app.RT60,'off')
            legend(app.RT60,'show','location','best')
        end

        % Button pushed function: OctaveButton
        function OctaveButtonPushed(app, event)
            global EDT_oct
            global T20_oct
            global T30_oct
            global Filter
            global fcenter
            cla(app.RT60,'reset')
            hold(app.RT60,'on')
            plot(app.RT60, fcenter, EDT_oct, 'DisplayName', 'EDT 1/3 Octave');
            plot(app.RT60, fcenter, T20_oct, 'DisplayName', 'T20 1/3 Octave');
            plot(app.RT60, fcenter, T30_oct, 'DisplayName', 'T30 1/3 Octave');
            hold(app.RT60,'off')
            legend(app.RT60,'show','location','best')
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            global EDT
            global T20
            global T30
            global Filter
            cla(app.RT60,'reset')
            hold(app.RT60,'on')
            plot(app.RT60, Filter, EDT, 'DisplayName', 'EDT original');
            plot(app.RT60, Filter, T20, 'DisplayName', 'T20 original');
            plot(app.RT60, Filter, T30, 'DisplayName', 'T30 original');
            hold(app.RT60,'off')
            legend(app.RT60,'show','location','best')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RT60 = uiaxes(app.UIFigure);
            title(app.RT60, 'RT Channel')
            xlabel(app.RT60, 'Filtered Signal Bands (Hz)')
            ylabel(app.RT60, 'Reverberation Time (s)')
            app.RT60.XScale = 'log';
            app.BarkButton = uibutton(app.UIFigure, 'push');
            app.BarkButton.ButtonPushedFcn = createCallbackFcn(app, @BarkButtonPushed, true);
            app.BarkButton.Text = 'Bark';
            app.OctaveButton = uibutton(app.UIFigure, 'push');
            app.OctaveButton.ButtonPushedFcn = createCallbackFcn(app, @OctaveButtonPushed, true);
            app.OctaveButton.Text = 'Octave';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RT60_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end