classdef SineSweepMethod_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        text34        matlab.ui.control.Label
        text33        matlab.ui.control.Label
        text32        matlab.ui.control.Label
        text30        matlab.ui.control.Label
        text29        matlab.ui.control.Label
        text3         matlab.ui.control.Label
        text2         matlab.ui.control.Label
        SweepType     matlab.ui.control.DropDown
        FileName      matlab.ui.control.EditField
        Disp          matlab.ui.control.CheckBox
        OK            matlab.ui.control.Button
        Cancel        matlab.ui.control.Button
        text13        matlab.ui.control.Label
        text15        matlab.ui.control.Label
        text17        matlab.ui.control.Label
        text16        matlab.ui.control.Label
        text5         matlab.ui.control.Label
        Freq2         matlab.ui.control.EditField
        text33_2      matlab.ui.control.Label
        text4         matlab.ui.control.Label
        text34_2      matlab.ui.control.Label
        text7         matlab.ui.control.Label
        Averages      matlab.ui.control.EditField
        text12        matlab.ui.control.Label
        text6         matlab.ui.control.Label
        Duration      matlab.ui.control.EditField
        text33_3      matlab.ui.control.Label
        text34_3      matlab.ui.control.Label
        text33_4      matlab.ui.control.Label
        text8         matlab.ui.control.Label
        SilenceZones  matlab.ui.control.EditField
        text9         matlab.ui.control.Label
        RecMode       matlab.ui.control.DropDown
        Freq1         matlab.ui.control.EditField
        text10        matlab.ui.control.Label
        SampFreq      matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function SineSweepMethod_OpeningFcn(app)
%             % Choose default command line output for SineSweepMethod
%             app.output = hObject;
%             
%             guidata(hObject, handles)
        end

        % Value changed function: Averages
        function Averages_Callback(app, event)
            global number;
            global UpdatedApp;
            strnumber=get(app.Averages, 'Value');
            number=str2double(strnumber);
            UpdatedApp.number=number;
        end

        % Button pushed function: Cancel
        function Cancel_Callback(app, event)
            close(app.figure1);
            AS_App
        end

        % Value changed function: Disp
        function Disp_Callback(app, event)
%             global verbose
%             verbose=0;
%             if (get(hObject,'Value') == get(hObject,'Max'))
%             	verbose=1;
%             else
%             	verbose=0;
%             end
%             UpdatedApp.verbose=verbose;

        end

        % Value changed function: Duration
        function Duration_Callback(app, event)
            global time;
            global UpdatedApp;
            strtime=get(app.Duration, 'Value');
            time=str2double(strtime);
            UpdatedApp.time=time;
        end

        % Value changed function: FileName
        function FileName_Callback(app, event)
            global UpdatedApp;
            global name;
            name='IR';
            name=app.FileName.Value;
            UpdatedApp.name=name;
        end

        % Callback function
        function Freq1_Callback(app, event)
            global f1;
            global UpdatedApp;
            strf1=get(app.Freq1, 'Value');
            f1=str2double(strf1);
            UpdatedApp.f1=f1;
        end

        % Value changed function: Freq2
        function Freq2_Callback(app, event)
            global f2;
            global UpdatedApp;
            strf2=get(app.Freq2, 'Value');
            f2=str2double(strf2);
            UpdatedApp.f2=f2;
        end

        % Button pushed function: OK
        function OK_Callback(app, event)
            global lin;
            global UpdatedApp;
            global name;
            global f1;
            global f2;
            global time;
            global number;
            global silence;
            global mode;
            global fs;
            global verbose;
            %lin=UpdatedApp.lin;
            %name=UpdatedApp.name;
%             f1=UpdatedApp.f1;
%             f2=UpdatedApp.f2;
%             time=UpdatedApp.time;
%             number=UpdatedApp.number;
%             silence=UpdatedApp.silence;
%             mode=UpdatedApp.mode;
%             fs=UpdatedApp.fs;
%             verbose=UpdatedApp.verbose;
            assignin ('base','lin',lin)
            assignin ('base','name',name)
            assignin ('base','f1',f1)
            assignin ('base', 'f2', f2)
            assignin ('base', 'time', time)
            assignin ('base', 'number', number)
            assignin ('base', 'silence', silence)
            assignin ('base', 'mode', mode)
            assignin ('base', 'fs', fs)
            assignin ('base', 'verbose', verbose)
            preequal=evalin('base','preequal');
            close(app.figure1);
            setpath;
            cd(MEASURES_SWEEP_PATH);
            
            SWEEPmeasurement(name,f1,f2,time,number,silence,mode,fs,verbose,preequal,lin);
        end

        % Value changed function: RecMode
        function RecMode_Callback(app, event)
            global mode;
            global UpdatedApp;
            mode=0;
            str = app.RecMode.Items;
            val = app.RecMode.Value;
            switch val;
            case 'mono-right' % User selects mono-left.
               UpdatedApp.current_data = 0;
               mode=0;
            case 'mono-left' % User selects mono-left.
               UpdatedApp.current_data = 1;
               mode=1;
            case 'stereo' % User selects stereo.
               UpdatedApp.current_data = 2;
               mode=2;
            end
            UpdatedApp.mode = mode;
        end

        % Value changed function: SampFreq
        function SampFreq_Callback(app, event)
            global fs;
            global UpdatedApp;
            strfs=get(app.SampFreq, 'Value');
            fs=str2double(strfs);
            UpdatedApp.fs=fs;
        end

        % Value changed function: SilenceZones
        function SilenceZones_Callback(app, event)
            global silence;
            global UpdatedApp;
            strsilence=get(app.SilenceZones, 'Value');
            silence=str2double(strsilence);
            UpdatedApp.silence=silence;
        end

        % Value changed function: SweepType
        function SweepType_Callback(app, event)
            global lin;
            global UpdatedApp;
            lin=0;
            str = app.SweepType.Items;
            val = app.SweepType.Value;
            switch val;
            case 'logarithmic' % User selects peaks.
               %UpdatedApp.lin = 0;
               lin=0;
            case 'linear' % User selects membrane.
               %UpdatedApp.lin = 1;
               lin=1;
            end
            UpdatedApp.lin = lin;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'SineSweepMethod';
            app.text34 = uilabel(app.figure1);
            app.text34.Text = '';
            app.text33 = uilabel(app.figure1);
            app.text33.Text = '';
            app.text32 = uilabel(app.figure1);
            app.text32.Text = '';
            app.text30 = uilabel(app.figure1);
            app.text30.Text = '';
            app.text29 = uilabel(app.figure1);
            app.text29.Text = '';
            app.text3 = uilabel(app.figure1);
            app.text3.Text = 'File name that will contain Impulse Response';
            app.text2 = uilabel(app.figure1);
            app.text2.Text = 'Type of Sweep';
            app.SweepType = uidropdown(app.figure1);
            app.SweepType.Items = {'logarithmic', 'linear'};
            app.SweepType.ValueChangedFcn = createCallbackFcn(app, @SweepType_Callback, true);
            app.SweepType.Value = 'logarithmic';
            app.FileName = uieditfield(app.figure1, 'text');
            app.FileName.ValueChangedFcn = createCallbackFcn(app, @FileName_Callback, true);
            app.FileName.Value = 'IR';
            app.Disp = uicheckbox(app.figure1);
            app.Disp.ValueChangedFcn = createCallbackFcn(app, @Disp_Callback, true);
            app.Disp.Text = 'Display the menu';
            app.OK = uibutton(app.figure1, 'push');
            app.OK.ButtonPushedFcn = createCallbackFcn(app, @OK_Callback, true);
            app.OK.Text = 'O K';
            app.Cancel = uibutton(app.figure1, 'push');
            app.Cancel.ButtonPushedFcn = createCallbackFcn(app, @Cancel_Callback, true);
            app.Cancel.Text = 'C A N C E L';
            app.text13 = uilabel(app.figure1);
            app.text13.Tooltip = 'To prevent time-aliasing';
            app.text13.Text = '?';
            app.text15 = uilabel(app.figure1);
            app.text15.Tooltip = 'For linear, distortion noise appears everywhere on the time axis while for logarithmic, distortions appear before the impulse response in the form of harmonics';
            app.text15.Text = '?';
            app.text17 = uilabel(app.figure1);
            app.text17.Text = 'OBTAIN IMPULSE RESPONSE SETTINGS';
            app.text16 = uilabel(app.figure1);
            app.text16.Text = '* Hover your mouse over the ''?'' sign to get an explanation';
            app.text5 = uilabel(app.figure1);
            app.text5.Text = 'Final Frequency of the Sweep (Hz)';
            app.Freq2 = uieditfield(app.figure1, 'text');
            app.Freq2.ValueChangedFcn = createCallbackFcn(app, @Freq2_Callback, true);
            app.Freq2.Value = '20000';
            app.text33_2 = uilabel(app.figure1);
            app.text33_2.Text = '';
            app.text4 = uilabel(app.figure1);
            app.text4.Text = 'Initial Frequency of the Sweep (Hz)';
            app.text34_2 = uilabel(app.figure1);
            app.text34_2.Text = '';
            app.text7 = uilabel(app.figure1);
            app.text7.Text = 'Number of desired averages';
            app.Averages = uieditfield(app.figure1, 'text');
            app.Averages.ValueChangedFcn = createCallbackFcn(app, @Averages_Callback, true);
            app.Averages.Value = '1';
            app.text12 = uilabel(app.figure1);
            app.text12.Tooltip = 'To remove unwanted noise, which improves signal-to-noise ratio';
            app.text12.Text = '?';
            app.text6 = uilabel(app.figure1);
            app.text6.Text = 'Duration of the Sweep (s)';
            app.Duration = uieditfield(app.figure1, 'text');
            app.Duration.ValueChangedFcn = createCallbackFcn(app, @Duration_Callback, true);
            app.Duration.Value = '1';
            app.text33_3 = uilabel(app.figure1);
            app.text33_3.Text = '';
            app.text34_3 = uilabel(app.figure1);
            app.text34_3.Text = '';
            app.text33_4 = uilabel(app.figure1);
            app.text33_4.Text = '';
            app.text8 = uilabel(app.figure1);
            app.text8.Text = 'Duration of silence zones after each Sweep (s)';
            app.SilenceZones = uieditfield(app.figure1, 'text');
            app.SilenceZones.ValueChangedFcn = createCallbackFcn(app, @SilenceZones_Callback, true);
            app.SilenceZones.Value = '1';
            app.text9 = uilabel(app.figure1);
            app.text9.Text = 'Recording mode';
            app.RecMode = uidropdown(app.figure1);
            app.RecMode.Items = {'mono-right', 'mono-left', 'stereo'};
            app.RecMode.ValueChangedFcn = createCallbackFcn(app, @RecMode_Callback, true);
            app.RecMode.Value = 'mono-right';
            app.Freq1 = uieditfield(app.figure1, 'text');
            app.Freq1.Value = '20';
            app.text10 = uilabel(app.figure1);
            app.text10.Text = 'Frequency of sampling (Hz)';
            app.SampFreq = uieditfield(app.figure1, 'text');
            app.SampFreq.ValueChangedFcn = createCallbackFcn(app, @SampFreq_Callback, true);
            app.SampFreq.Value = '44100';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SineSweepMethod_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @SineSweepMethod_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end