classdef Transform_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1          matlab.ui.Figure
        text16           matlab.ui.control.Label
        text15           matlab.ui.control.Label
        text14           matlab.ui.control.Label
        text13           matlab.ui.control.Label
        text12           matlab.ui.control.Label
        text11           matlab.ui.control.Label
        text10           matlab.ui.control.Label
        text3            matlab.ui.control.Label
        browse1          matlab.ui.control.Button
        text4            matlab.ui.control.Label
        browse2          matlab.ui.control.Button
        play             matlab.ui.control.Button
        stop1            matlab.ui.control.Button
        transformbutton  matlab.ui.control.Button
        stop2            matlab.ui.control.Button
        cancel           matlab.ui.control.Button
        save             matlab.ui.control.Button
        text5            matlab.ui.control.Label
        location         matlab.ui.control.Button
        Angletext        matlab.ui.control.Label
        colorbox         matlab.ui.control.CheckBox
        anglebox         matlab.ui.control.CheckBox
        text7            matlab.ui.control.Label
        edit3            matlab.ui.control.Label
        text8            matlab.ui.control.Label
        text9            matlab.ui.control.Label
        ele              matlab.ui.control.Label
        ang              matlab.ui.control.Label
        colorfile        matlab.ui.control.Label
        audio            matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Transform_OpeningFcn(app)
%             % Choose default command line output for Transform
%             app.output = hObject;
%             app.ele = 0;
 %            app.ang = 0;
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Value changed function: anglebox
        function anglebox_Callback(app, event)
            value = app.anglebox.Value;
            
        end

        % Callback function
        function audio_Callback(app, event)
            guidata(hObject,handles);
        end

        % Button pushed function: browse1
        function browse1_Callback(app, event)
            setpath;
            current = pwd;
            cd(TEST_SIGNALS);
            [file_1, path_1] = uigetfile('*','Select the sound or music to be transformed');
            cd(current);
            set(app.audio, 'Text', strcat(path_1,file_1));
        end

        % Button pushed function: browse2
        function browse2_Callback(app, event)
            setpath;
            current = pwd;
            cd(COLOR_PATH);
            [file_2, path_2] = uigetfile('*','Select the Impulse Response for the room');
            cd(current);
            set(app.colorfile,'Text',strcat(path_2,file_2));
        end

        % Button pushed function: cancel
        function cancel_Callback(app, event)
            clear sound;
            close(app.figure1);
            AS_App;
        end

        % Value changed function: colorbox
        function colorbox_Callback(app, event)
            guidata(hObject,handles);
        end

        % Callback function
        function colorfile_Callback(app, event)
            guidata(hObject,handles);
        end

        % Button pushed function: location
        function location_Callback(app, event)
            h = getlocation_App;
            waitfor(h);
            set(app.anglebox,'value',1);
            ele = evalin('base','elevation');
            ang = evalin('base','angle');
            text = sprintf('Elevation Angle(°): \nAzimuth Angle(°):',ele,ang);
            set(app.Angletext,'Text',text);
            app.ele.Text = num2str(ele);
            app.ang.Text = num2str(ang);
        end

        % Button pushed function: play
        function play_Callback(app, event)
            clear sound;
            path1 = get(app.audio,'Text');
            [y_1,Fs_1] = audioread(path1);
            sound(y_1,Fs_1);
        end

        % Button pushed function: save
        function save_Callback(app, event)
        global UpdatedApp;    
        y_out = UpdatedApp.y_out;
            fs = UpdatedApp.Fs_1;
            setpath;
            current = pwd;
            cd(SAVED_PATH);
            [file,path] = uiputfile('*.wav;*.flac;*.m4a;*.mp4;*.ogg','Save transformed audio');
            audiowrite(file, y_out,fs); %strcat(path,file),
            cd(current);
            h = msgbox('Transformed audio saved','Success');
        end

        % Button pushed function: stop1, stop2
        function stop_Callback(app, event)
            clear sound;
        end

        % Button pushed function: transformbutton
        function transformbutton_Callback(app, event)
        global UpdatedApp;    
        clear sound;
            path1 = get(app.audio,'Text');
            [y_1,Fs_1] = audioread(path1); %original audio;
            UpdatedApp.Fs_1 = Fs_1;
            if min(size(y_1)) == 2
                k = 2;
            else
                k =1;
            end
            convoluted = 0;
%             hey(app.colorbox.Value);
            if get(app.anglebox,'Value') == 1
                ele = app.ele.Text;
                ang = app.ang.Text;
                [leftir,rightir] = givelocationwav_app(ele,ang);
                y_left = audioread(leftir);
                y_right = audioread(rightir);
                Conv_result = [convlin(y_1(:,1),y_left(:,1),' (location - left)') convlin(y_1(:,k),y_right(:,1),' (location - right)')];
                convoluted = 1;
            end
            
            if get(app.colorbox,'Value') == 1
                path2 = get(app.colorfile,'Text');
                y_2 = audioread(path2); %coloring IR
            
                if min(size(y_2)) == 2
                m = 2;
                else
                m = 1;
                end
            
                if convoluted == 1
                    k = 2;
                else
                    Conv_result = y_1;
                end
            
                Conv_result = [convlin(Conv_result(:,1),y_2(:,1),' (scenery - left)') convlin(Conv_result(:,k),y_2(:,m),' (scenery - right)')];
                convoluted = 1;
            end
            
            if convoluted ~= 1
                h = msgbox('No convolution operation was selected','error');
                return
            end
            
            Conv_result = trimmatch(Conv_result);
            maximum = max(Conv_result);
            Conv_result_normalised(:,1) = 0.95*(Conv_result(:,1)/maximum(1));
            Conv_result_normalised(:,2) = 0.95*(Conv_result(:,2)/maximum(2));
            
            %app.y_out = Conv_result_normalised;
            UpdatedApp.y_out = Conv_result_normalised;
            sound(Conv_result_normalised,Fs_1)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Transform';
            app.text16 = uilabel(app.figure1);
            app.text16.Text = {'Elevation Angle: 0°'; 'Azimuth Angle: 0°  '};
            app.text15 = uilabel(app.figure1);
            app.text15.Text = {'Elevation Angle: 0°'; 'Azimuth Angle: 0°  '};
            app.text14 = uilabel(app.figure1);
            app.text14.Text = {'Elevation Angle: 0°'; 'Azimuth Angle: 0°  '};
            app.text13 = uilabel(app.figure1);
            app.text13.Text = '';
            app.text12 = uilabel(app.figure1);
            app.text12.Text = '';
            app.text11 = uilabel(app.figure1);
            app.text11.Text = '';
            app.text10 = uilabel(app.figure1);
            app.text10.Text = '';
            app.text3 = uilabel(app.figure1);
            app.text3.Text = ' 1. Select your sound or music:';
            app.browse1 = uibutton(app.figure1, 'push');
            app.browse1.ButtonPushedFcn = createCallbackFcn(app, @browse1_Callback, true);
            app.browse1.Text = 'B R O W S E';
            app.text4 = uilabel(app.figure1);
            app.text4.Text = ' 2. Select Impulse Response of your desired room:';
            app.browse2 = uibutton(app.figure1, 'push');
            app.browse2.ButtonPushedFcn = createCallbackFcn(app, @browse2_Callback, true);
            app.browse2.Text = 'B R O W S E';
            app.play = uibutton(app.figure1, 'push');
            app.play.ButtonPushedFcn = createCallbackFcn(app, @play_Callback, true);
            app.play.Text = 'P L A Y';
            app.stop1 = uibutton(app.figure1, 'push');
            app.stop1.ButtonPushedFcn = createCallbackFcn(app, @stop_Callback, true);
            app.stop1.Text = 'S T O P';
            app.transformbutton = uibutton(app.figure1, 'push');
            app.transformbutton.ButtonPushedFcn = createCallbackFcn(app, @transformbutton_Callback, true);
            app.transformbutton.Text = 'T R A N S F O R M !';
            app.stop2 = uibutton(app.figure1, 'push');
            app.stop2.ButtonPushedFcn = createCallbackFcn(app, @stop_Callback, true);
            app.stop2.Text = 'S T O P';
            app.cancel = uibutton(app.figure1, 'push');
            app.cancel.ButtonPushedFcn = createCallbackFcn(app, @cancel_Callback, true);
            app.cancel.Text = 'C A N C E L';
            app.save = uibutton(app.figure1, 'push');
            app.save.ButtonPushedFcn = createCallbackFcn(app, @save_Callback, true);
            app.save.Text = 'S A V E';
            app.text5 = uilabel(app.figure1);
            app.text5.Text = ' 3. Select location of the audio source:';
            app.location = uibutton(app.figure1, 'push');
            app.location.ButtonPushedFcn = createCallbackFcn(app, @location_Callback, true);
            app.location.Text = 'S E L E C T   L O C A T I O N';
            app.Angletext = uilabel(app.figure1);
            app.Angletext.Text = {'Elevation Angle:'; 'Azimuth Angle:  '};
            app.colorbox = uicheckbox(app.figure1);
            app.colorbox.ValueChangedFcn = createCallbackFcn(app, @colorbox_Callback, true);
            app.colorbox.Text = '';
            app.colorbox.Value = true;
            app.anglebox = uicheckbox(app.figure1);
            app.anglebox.ValueChangedFcn = createCallbackFcn(app, @anglebox_Callback, true);
            app.anglebox.Text = '';
            app.text7 = uilabel(app.figure1);
            app.text7.Text = '*Compulsary Field';
            app.edit3 = uilabel(app.figure1);
            app.edit3.Text = 'EXPERIENCE YOUR SOUND IN A DIFFERENT DIMENSION';
            app.text8 = uilabel(app.figure1);
            app.text8.Text = '(Sound Colouration)';
            app.text9 = uilabel(app.figure1);
            app.text9.Text = '(Sound Localization)';
            app.ele = uilabel(app.figure1);
            app.ele.Text = '0';
            app.ang = uilabel(app.figure1);
            app.ang.Text = '0';
            app.colorfile = uilabel(app.figure1);
            app.colorfile.Text = '';
            app.audio = uilabel(app.figure1);
            app.audio.Text = '';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Transform_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Transform_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end