classdef getlocation_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1        matlab.ui.Figure
        axes1          matlab.ui.control.UIAxes
        text18         matlab.ui.control.Label
        uicontrol1     matlab.ui.control.Label
        uicontrol1_1   matlab.ui.control.Label
        uicontrol1_2   matlab.ui.control.Label
        uicontrol1_3   matlab.ui.control.Label
        uicontrol1_4   matlab.ui.control.Label
        uicontrol1_5   matlab.ui.control.Label
        uicontrol1_6   matlab.ui.control.Label
        uicontrol1_7   matlab.ui.control.Label
        uicontrol1_8   matlab.ui.control.Label
        uicontrol1_9   matlab.ui.control.Label
        uicontrol1_10  matlab.ui.control.Label
        uicontrol1_11  matlab.ui.control.Label
        uicontrol1_12  matlab.ui.control.Label
        uicontrol1_13  matlab.ui.control.Label
        uicontrol1_14  matlab.ui.control.Label
        uicontrol1_15  matlab.ui.control.Label
        slider1        matlab.ui.control.Slider
        slider2        matlab.ui.control.Slider
        OK             matlab.ui.control.Button
        Cancel         matlab.ui.control.Button
        Elevationtext  matlab.ui.control.Label
        Angletext      matlab.ui.control.Label
        text8          matlab.ui.control.Label
        text9          matlab.ui.control.Label
        text10         matlab.ui.control.Label
        text11         matlab.ui.control.Label
        text13         matlab.ui.control.Label
        text16         matlab.ui.control.Label
        text12         matlab.ui.control.Label
        text15         matlab.ui.control.Label
        text20         matlab.ui.control.Label
    end

    
    properties (Access = private)
        elev
        angl
        ash1
        ash2
        ash3
    end
    
    methods (Access = private)
        
        function plotInitial(app)
            [x, y, z] = sphere(128);
            app.axes1.DataAspectRatio = [1 1 1];
            rotate3d(app.axes1,'on');
            my_colour = [20 173 23] ./ 255;
            app.axes1.NextPlot = 'add';
            app.axes1.XGrid = 'off';
            app.axes1.YGrid = 'off';
            app.axes1.ZGrid = 'off';
            sh1 = surf(app.axes1, x, y, z, 'FaceAlpha', 0.2, 'EdgeAlpha', 0, 'EdgeColor', 'none');
            app.ash1 = sh1;
            t = [0.1,0];
            [y,z,x] = cylinder(t);
            sh3 = surf(app.axes1, x,y,z, 'FaceColor', my_colour, 'FaceAlpha', 0.8, 'EdgeAlpha', 0, 'EdgeColor','none');
            app.ash3 = sh3;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function getlocation_OpeningFcn(app)
            plotInitial(app)
            ele = app.slider1.Value;
            app.elev = ele;
            ang = app.slider2.Value;
            app.angl = ang;
            elevation = sprintf('Elevation Angle: %.f°',ele);
            angle = sprintf('Azimuth Angle: %.f°',ang);
            app.Elevationtext.Text = elevation;
            app.Angletext.Text = angle;
            %assignin('base', 'axes', app.axes1)
            %plot(app.axes1, sh3)
            %my_colour = [20 173 23] ./ 255;
            %sh2 = plotsphere1(app.axes1,ele,ang,0);
            %app.ash2 = sh2;
%             % Choose default command line output for getlocation
%             app.output = hObject;
%             ele = evalin('base','elevation');
%             ang = evalin('base','angle');
%             elevation = sprintf('Elevation Angle: %.f°',ele);
%             set(app.Elevationtext,'string',elevation);
%             set(app.slider1,'value',ele);
%             angle = sprintf('Azimuth Angle: %.f°',ang);
%             set(app.Angletext,'string',angle);
%             set(app.slider2,'value',ang);
%             app.ele = ele;
%             app.ang = ang;
%             sh2 = plotsphere(ele,ang,0);
%             app.sh2 = sh2;
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Button pushed function: Cancel
        function Cancel_Callback(app, event)
            close(app.figure1);
        end

        % Button pushed function: OK
        function OK_Callback(app, event)
            ele = app.elev;
            assignin('base','elevation',ele);
            ang = app.angl;
            assignin('base','angle',ang);
            %guidata(hObject, handles);
            close(app.figure1);
        end

        % Value changed function: slider1
        function slider1_Callback(app, event)
            cla(app.axes1)
            plotInitial(app)
            ele = app.slider1.Value;
            ele = round(ele/15)*15;
            elevation = sprintf('Elevation Angle: %.f°',ele);
            app.Elevationtext.Text = elevation;
            %set(app.Elevationtext,'string',elevation);
            app.elev = ele;
            [x, y, z] = sphere(128);
            [x,y,z]=sphere(128);
            [xs,ys,zs] = sph2cart(deg2rad(app.angl),deg2rad(ele),1);
            sh2 = surf(app.axes1, 0.05*x+xs,0.05*y+ys,0.05*z+zs, 'FaceColor', [1 1 0], 'EdgeAlpha', 0, 'EdgeColor', 'none');
            %[x, y, z] = sphere(128);
            %[xs,ys,zs] = sph2cart(deg2rad(app.angl),deg2rad(ele),1);
            %sh2 = surf(0.05*x+xs,0.05*y+ys,0.05*z+zs);
            %sh2 = plotsphere1(app.axes1, ele,app.angl,1,sh2);
            %plot(app.axes1, sh2)
            app.ash2 = sh2;
            %guidata(hObject, handles);
        end

        % Value changed function: slider2
        function slider2_Callback(app, event)
            cla(app.axes1)
            app.axes1.NextPlot = 'add';
            plotInitial(app)
            ang = app.slider2.Value;
            ang = round(ang/15)*15;
            angle = sprintf('Azimuth Angle: %.f°',ang);
            app.Angletext.Text = angle;
            %set(app.Angletext,'string',angle);
            app.angl = ang;
            [x,y,z]=sphere(128);
            [xs,ys,zs] = sph2cart(deg2rad(ang),deg2rad(app.elev),1);
            sh2 = surf(app.axes1, 0.05*x+xs,0.05*y+ys,0.05*z+zs, 'FaceColor', [1 1 0], 'EdgeAlpha', 0);
            %sh2 = plotsphere1(app.axes1, app.elev,ang,1,sh2);
            %[x, y, z] = sphere(128);
            %[xs,ys,zs] = sph2cart(deg2rad(ang),deg2rad(app.elev),1);
            %sh2 = surf(0.05*x+xs,0.05*y+ys,0.05*z+zs);
            %plot(app.axes1, sh2)
            app.ash2 = sh2;
            %guidata(hObject, handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'getlocation';
            app.axes1 = uiaxes(app.figure1);
            app.text18 = uilabel(app.figure1);
            app.text18.Text = '';
            app.uicontrol1 = uilabel(app.figure1);
            app.uicontrol1.Visible = 'off';
            app.uicontrol1.Text = '';
            app.uicontrol1_1 = uilabel(app.figure1);
            app.uicontrol1_1.Visible = 'off';
            app.uicontrol1_1.Text = '';
            app.uicontrol1_2 = uilabel(app.figure1);
            app.uicontrol1_2.Visible = 'off';
            app.uicontrol1_2.Text = '';
            app.uicontrol1_3 = uilabel(app.figure1);
            app.uicontrol1_3.Visible = 'off';
            app.uicontrol1_3.Text = '';
            app.uicontrol1_4 = uilabel(app.figure1);
            app.uicontrol1_4.Visible = 'off';
            app.uicontrol1_4.Text = '';
            app.uicontrol1_5 = uilabel(app.figure1);
            app.uicontrol1_5.Visible = 'off';
            app.uicontrol1_5.Text = '';
            app.uicontrol1_6 = uilabel(app.figure1);
            app.uicontrol1_6.Visible = 'off';
            app.uicontrol1_6.Text = '';
            app.uicontrol1_7 = uilabel(app.figure1);
            app.uicontrol1_7.Visible = 'off';
            app.uicontrol1_7.Text = '';
            app.uicontrol1_8 = uilabel(app.figure1);
            app.uicontrol1_8.Visible = 'off';
            app.uicontrol1_8.Text = '';
            app.uicontrol1_9 = uilabel(app.figure1);
            app.uicontrol1_9.Visible = 'off';
            app.uicontrol1_9.Text = '';
            app.uicontrol1_10 = uilabel(app.figure1);
            app.uicontrol1_10.Visible = 'off';
            app.uicontrol1_10.Text = '';
            app.uicontrol1_11 = uilabel(app.figure1);
            app.uicontrol1_11.Visible = 'off';
            app.uicontrol1_11.Text = '';
            app.uicontrol1_12 = uilabel(app.figure1);
            app.uicontrol1_12.Visible = 'off';
            app.uicontrol1_12.Text = '';
            app.uicontrol1_13 = uilabel(app.figure1);
            app.uicontrol1_13.Visible = 'off';
            app.uicontrol1_13.Text = '';
            app.uicontrol1_14 = uilabel(app.figure1);
            app.uicontrol1_14.Visible = 'off';
            app.uicontrol1_14.Text = '';
            app.uicontrol1_15 = uilabel(app.figure1);
            app.uicontrol1_15.Visible = 'off';
            app.uicontrol1_15.Text = '';
            app.slider1 = uislider(app.figure1);
            app.slider1.Limits = [-45 90];
            app.slider1.MajorTicks = [];
            app.slider1.ValueChangedFcn = createCallbackFcn(app, @slider1_Callback, true);
            app.slider1.MinorTicks = [];
            app.slider2 = uislider(app.figure1);
            app.slider2.Limits = [0 360];
            app.slider2.MajorTicks = [];
            app.slider2.ValueChangedFcn = createCallbackFcn(app, @slider2_Callback, true);
            app.slider2.MinorTicks = [];
            app.OK = uibutton(app.figure1, 'push');
            app.OK.ButtonPushedFcn = createCallbackFcn(app, @OK_Callback, true);
            app.OK.Text = 'O K';
            app.Cancel = uibutton(app.figure1, 'push');
            app.Cancel.ButtonPushedFcn = createCallbackFcn(app, @Cancel_Callback, true);
            app.Cancel.Text = 'C A N C E L';
            app.Elevationtext = uilabel(app.figure1);
            app.Elevationtext.Text = ' Elevation Angle: 0°';
            app.Angletext = uilabel(app.figure1);
            app.Angletext.Text = ' Azimuth Angle: 0°';
            app.text8 = uilabel(app.figure1);
            app.text8.Text = '-45°';
            app.text9 = uilabel(app.figure1);
            app.text9.Text = '90°';
            app.text10 = uilabel(app.figure1);
            app.text10.Text = '0°';
            app.text11 = uilabel(app.figure1);
            app.text11.Text = '360°';
            app.text13 = uilabel(app.figure1);
            app.text13.Text = 'shows location of sound';
            app.text16 = uilabel(app.figure1);
            app.text16.Text = 'Yellow dot';
            app.text12 = uilabel(app.figure1);
            app.text12.Text = 'points at face direction';
            app.text15 = uilabel(app.figure1);
            app.text15.Text = 'Green arrow';
            app.text20 = uilabel(app.figure1);
            app.text20.Text = 'LOCATE THE SOURCE OF SOUND';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = getlocation_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @getlocation_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end