classdef harmonicdistortionapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MainaudiofilePanel              matlab.ui.container.Panel
        UsefilefromparentappCheckBox    matlab.ui.control.CheckBox
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        ORLabel                         matlab.ui.control.Label
        SelectfileButton                matlab.ui.control.Button
        BackgroundaudiofilePanel        matlab.ui.container.Panel
        FilenameEditField_2Label        matlab.ui.control.Label
        FilenameEditField_2             matlab.ui.control.EditField
        ORLabel_2                       matlab.ui.control.Label
        SelectfileButton_2              matlab.ui.control.Button
        UsebackgroundaudiofileCheckBox  matlab.ui.control.CheckBox
        NumberofharmonicstoremoveEditFieldLabel  matlab.ui.control.Label
        NumberofharmonicstoremoveEditField  matlab.ui.control.NumericEditField
        NumberofharmonicstoviewindividuallyEditFieldLabel  matlab.ui.control.Label
        NumberofharmonicstoviewindividuallyEditField  matlab.ui.control.NumericEditField
        NumberofharmonicstoviewasnoiseEditFieldLabel  matlab.ui.control.Label
        NumberofharmonicstoviewasnoiseEditField  matlab.ui.control.NumericEditField
        Harmonicnumbertohighlight0ifnoneEditFieldLabel  matlab.ui.control.Label
        Harmonicnumbertohighlight0ifnoneEditField  matlab.ui.control.NumericEditField
        PlotharmonicsCheckBox           matlab.ui.control.CheckBox
        PlotTHCCheckBox                 matlab.ui.control.CheckBox
        PlotsignalwithoutharmonicsCheckBox  matlab.ui.control.CheckBox
        RemoveharmonicsButton           matlab.ui.control.Button
    end


    properties (Access = private)
        parentApp
        backgroundNoise
        samplingFreq
        signal
        unsmoothedAxis
        mainFileName
        clearSignal
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            app.parentApp = parentApp;
        end

        % Button pushed function: RemoveharmonicsButton
        function RemoveharmonicsButtonPushed(app, event)
            if app.UsefilefromparentappCheckBox.Value == 1
                app.mainFileName= app.parentApp.fileName;
                app.FilenameEditField.Value = app.mainFileName;
            else
                app.mainFileName = app.FilenameEditField.Value;
            end
            [app.signal, app.samplingFreq] = audioread(app.mainFileName);
            app.unsmoothedAxis = app.samplingFreq*(0:length(app.signal)-1)/length(app.signal);
            taxis = (1/app.samplingFreq)*(0:length(app.signal)-1)/length(app.signal);
            if app.UsebackgroundaudiofileCheckBox.Value == 1
                [app.backgroundNoise, ~] = audioread(app.FilenameEditField_2.Value);
                [signalNoise, app.signal] = removeNoise(app.backgroundNoise, app.signal, app.samplingFreq, app.unsmoothedAxis);
            end
            num_peaks = app.NumberofharmonicstoremoveEditField.Value;
            num_peaks_view = app.NumberofharmonicstoviewindividuallyEditField.Value;
            num_peaks_view_noise = app.NumberofharmonicstoviewasnoiseEditField.Value;
            highlight_harmonic = app.Harmonicnumbertohighlight0ifnoneEditField.Value;
            if (num_peaks_view > num_peaks); num_peaks_view = num_peaks; end
            if (num_peaks_view_noise > num_peaks); num_peaks_view_noise = num_peaks; end
            if (highlight_harmonic > num_peaks); highlight_harmonic = 0; end
            
            % Find peaks
            app.clearSignal=PeakRemover(app.signal, app.samplingFreq, num_peaks); %This is the time domain signal without peaks
            ThreeP = AutoPeak(app.signal, app.samplingFreq); %This finds the first three peaks of the first three harmonics (including fundamental)
            m=MidFinder(ThreeP,num_peaks);%This finds the midpoint between all the harmonics
            m2=round(m(2)*app.samplingFreq); %Finds the midpoint between the fundamental and the first harmonic in seconds
            mend=round(m(end)*app.samplingFreq); %Finds the last midpoint between harmonics in seconds
            
            % plot signal without harmonics
            if app.PlotsignalwithoutharmonicsCheckBox.Value == 1
                clearsignalfig = uifigure;
                clearsignalAx = uiaxes(clearsignalfig, 'Position',[10 10 520 400]);
                clearsignalAx.XGrid = 'on';
                clearsignalAx.YGrid = 'on';
                clearsignalAx.Title.String = 'Time domain signal without harmonics';
                clearsignalAx.XLabel.String = 'Time (s)';
                plot(clearsignalAx, taxis, app.clearSignal)
            end
            % Plot harmonics
            if app.PlotharmonicsCheckBox.Value == 1
                ft_wins_hann = HarmonicFilt(app.signal, app.samplingFreq, num_peaks, m, app.unsmoothedAxis);% Function returns all the harmonics that have been windowed
                harmonicsfig = uifigure;
                harmonicsAx = uiaxes(harmonicsfig, 'Position',[10 10 520 400]);
                harmonicsAx.XScale = 'log';
                harmonicsAx.XLim = [15 20000]; % range of x axis
                harmonicsAx.XGrid = 'on';
                harmonicsAx.YGrid = 'on';
                harmonicsAx.Title.String = 'Harmonics';
                harmonicsAx.YLabel.String = 'Magnitude (dB)';
                harmonicsAx.XLabel.String = 'Frequency (Hz)';
                harmonicsAx.NextPlot = 'add';
                for i = 1:num_peaks_view
                    if i == highlight_harmonic 
                        if i == 1
                            plot(harmonicsAx, app.unsmoothedAxis, ft_wins_hann{i},'LineWidth',1.5, 'DisplayName', 'Fundamental'); %This plots the Emphasised harmonic
                        else
                            plot(harmonicsAx, app.unsmoothedAxis, ft_wins_hann{i},'LineWidth',1.5, 'DisplayName', char("H"+(i))); %This plots the Emphasised harmonic
                        end
                    else
                        if i == 1
                            plot(harmonicsAx, app.unsmoothedAxis, ft_wins_hann{i}, 'DisplayName', 'Fundamental'); %This plots the rest of the harmonics
                        else
                            plot(harmonicsAx, app.unsmoothedAxis, ft_wins_hann{i}, 'DisplayName', char("H"+(i)));
                        end
                    end
                end
                ft_wins_hann_noise = 0; %Plots noise harmonics
                if num_peaks_view_noise > num_peaks_view
                    for i = num_peaks_view+1:num_peaks_view_noise
                        ft_wins_hann_noise = ft_wins_hann_noise + 10.^(ft_wins_hann{i}./20);
                    end
                end
                if app.UsebackgroundaudiofileCheckBox.Value == 1
                    ft_wins_hann_noise = ft_wins_hann_noise + 10.^(signalNoise./20);
                end
                ft_wins_hann_noise = 20*log10(ft_wins_hann_noise);
                plot(harmonicsAx, app.unsmoothedAxis, ft_wins_hann_noise,'Color','#808080','LineStyle','--', 'DisplayName', 'Noise');
                legend(harmonicsAx, 'Location', 'northwest')
            end
            if app.PlotTHCCheckBox.Value == 1
                [THD, max_THD, freq_of_max_THD, min_THD, freq_of_min_THD] = calculateTHD(app.signal, app.samplingFreq, ft_wins_hann);
                THDfig = uifigure;
                THDAx = uiaxes(THDfig, 'Position',[10 10 520 400]);
                THDAx.XScale = 'log';
                THDAx.XLim = [15 20000]; % range of x axis
                THDAx.XGrid = 'on';
                THDAx.YGrid = 'on';
                THDAx.Title.String = 'Total harmonic distortion';
                THDAx.YLabel.String = 'THD (dB)';
                THDAx.XLabel.String = 'Frequency (Hz)';
                plot(THDAx, THD)
            end
        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            [file,path] = uigetfile('*'); % open file
            if isequal(file,0)
            else
               app.FilenameEditField.Value = fullfile(path, file);
            end
            drawnow; 
            figure(app.UIFigure) % refocus app (otherwise minimised)
        end

        % Button pushed function: SelectfileButton_2
        function SelectfileButton_2Pushed(app, event)
            [file,path] = uigetfile('*'); % open file
            if isequal(file,0)
            else
               app.FilenameEditField_2.Value = fullfile(path, file);
            end
            drawnow; 
            figure(app.UIFigure) % refocus app (otherwise minimised)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MainaudiofilePanel = uipanel(app.UIFigure);
            app.MainaudiofilePanel.Title = 'Main audio file';
            app.UsefilefromparentappCheckBox = uicheckbox(app.MainaudiofilePanel);
            app.UsefilefromparentappCheckBox.Text = 'Use file from parent app';
            app.FilenameEditFieldLabel = uilabel(app.MainaudiofilePanel);
            app.FilenameEditFieldLabel.Tooltip = {''};
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.MainaudiofilePanel, 'text');
            app.FilenameEditField.Tooltip = {'Enter an audio file name, including the file format at the end, e.g. .wav '};
            app.FilenameEditField.Value = 'GBS_Project.wav';
            app.ORLabel = uilabel(app.MainaudiofilePanel);
            app.ORLabel.Text = 'OR';
            app.SelectfileButton = uibutton(app.MainaudiofilePanel, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.BackgroundaudiofilePanel = uipanel(app.UIFigure);
            app.BackgroundaudiofilePanel.Title = 'Background audio file';
            app.FilenameEditField_2Label = uilabel(app.BackgroundaudiofilePanel);
            app.FilenameEditField_2Label.Tooltip = {''};
            app.FilenameEditField_2Label.Text = 'File name';
            app.FilenameEditField_2 = uieditfield(app.BackgroundaudiofilePanel, 'text');
            app.FilenameEditField_2.Tooltip = {'Enter an audio file name, including the file format at the end, e.g. .wav '};
            app.FilenameEditField_2.Value = 'GBS_Project_noise.wav';
            app.ORLabel_2 = uilabel(app.BackgroundaudiofilePanel);
            app.ORLabel_2.Text = 'OR';
            app.SelectfileButton_2 = uibutton(app.BackgroundaudiofilePanel, 'push');
            app.SelectfileButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButton_2Pushed, true);
            app.SelectfileButton_2.Text = 'Select file';
            app.UsebackgroundaudiofileCheckBox = uicheckbox(app.BackgroundaudiofilePanel);
            app.UsebackgroundaudiofileCheckBox.Text = 'Use background audio file';
            app.UsebackgroundaudiofileCheckBox.Value = true;
            app.NumberofharmonicstoremoveEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofharmonicstoremoveEditFieldLabel.Text = 'Number of harmonics to remove';
            app.NumberofharmonicstoremoveEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofharmonicstoremoveEditField.Limits = [0 Inf];
            app.NumberofharmonicstoremoveEditField.RoundFractionalValues = 'on';
            app.NumberofharmonicstoremoveEditField.ValueDisplayFormat = '%.0f';
            app.NumberofharmonicstoremoveEditField.Tooltip = {'Minimum frequency value to be displayed'};
            app.NumberofharmonicstoremoveEditField.Value = 15;
            app.NumberofharmonicstoviewindividuallyEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofharmonicstoviewindividuallyEditFieldLabel.Text = 'Number of harmonics to view individually';
            app.NumberofharmonicstoviewindividuallyEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofharmonicstoviewindividuallyEditField.Limits = [0 Inf];
            app.NumberofharmonicstoviewindividuallyEditField.RoundFractionalValues = 'on';
            app.NumberofharmonicstoviewindividuallyEditField.ValueDisplayFormat = '%.0f';
            app.NumberofharmonicstoviewindividuallyEditField.Tooltip = {'Minimum frequency value to be displayed'};
            app.NumberofharmonicstoviewindividuallyEditField.Value = 4;
            app.NumberofharmonicstoviewasnoiseEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofharmonicstoviewasnoiseEditFieldLabel.Text = 'Number of harmonics to view as noise';
            app.NumberofharmonicstoviewasnoiseEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofharmonicstoviewasnoiseEditField.Limits = [0 Inf];
            app.NumberofharmonicstoviewasnoiseEditField.RoundFractionalValues = 'on';
            app.NumberofharmonicstoviewasnoiseEditField.ValueDisplayFormat = '%.0f';
            app.NumberofharmonicstoviewasnoiseEditField.Tooltip = {'Minimum frequency value to be displayed'};
            app.NumberofharmonicstoviewasnoiseEditField.Value = 9;
            app.Harmonicnumbertohighlight0ifnoneEditFieldLabel = uilabel(app.UIFigure);
            app.Harmonicnumbertohighlight0ifnoneEditFieldLabel.Text = 'Harmonic number to highlight (0 if none)';
            app.Harmonicnumbertohighlight0ifnoneEditField = uieditfield(app.UIFigure, 'numeric');
            app.Harmonicnumbertohighlight0ifnoneEditField.Limits = [0 Inf];
            app.Harmonicnumbertohighlight0ifnoneEditField.RoundFractionalValues = 'on';
            app.Harmonicnumbertohighlight0ifnoneEditField.ValueDisplayFormat = '%.0f';
            app.Harmonicnumbertohighlight0ifnoneEditField.Tooltip = {'Minimum frequency value to be displayed'};
            app.Harmonicnumbertohighlight0ifnoneEditField.Value = 1;
            app.PlotharmonicsCheckBox = uicheckbox(app.UIFigure);
            app.PlotharmonicsCheckBox.Text = 'Plot harmonics';
            app.PlotharmonicsCheckBox.Value = true;
            app.PlotTHCCheckBox = uicheckbox(app.UIFigure);
            app.PlotTHCCheckBox.Text = 'Plot THC';
            app.PlotTHCCheckBox.Value = true;
            app.PlotsignalwithoutharmonicsCheckBox = uicheckbox(app.UIFigure);
            app.PlotsignalwithoutharmonicsCheckBox.Text = 'Plot signal without harmonics';
            app.PlotsignalwithoutharmonicsCheckBox.Value = true;
            app.RemoveharmonicsButton = uibutton(app.UIFigure, 'push');
            app.RemoveharmonicsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveharmonicsButtonPushed, true);
            app.RemoveharmonicsButton.Text = 'Remove harmonics';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = harmonicdistortionapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end