classdef updatedapp_task1to3_rectangularonly_with_harmonics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        SelectOptionsTab                matlab.ui.container.Tab
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        SelectfileButton                matlab.ui.control.Button
        ORLabel                         matlab.ui.control.Label
        MinimumfrequencyHzEditFieldLabel  matlab.ui.control.Label
        MinimumfrequencyHzEditField     matlab.ui.control.NumericEditField
        MaximumfrequencyHzEditFieldLabel  matlab.ui.control.Label
        MaximumfrequencyHzEditField     matlab.ui.control.NumericEditField
        FrequencydomainsmoothingPanel   matlab.ui.container.Panel
        ShowunsmoothedsignalgreyCheckBox  matlab.ui.control.CheckBox
        ShowrectangularmeansmoothedsignalblackCheckBox  matlab.ui.control.CheckBox
        LogmeansmoothedmagnitudeLabel   matlab.ui.control.Label
        GeometricmeansmoothedphaseandgroupdelayLabel  matlab.ui.control.Label
        RectangularsmoothingscaleDropDownLabel  matlab.ui.control.Label
        RectangularsmoothingscaleDropDown  matlab.ui.control.DropDown
        PlottingPanel                   matlab.ui.container.Panel
        MagnitudespectrumCheckBox       matlab.ui.control.CheckBox
        PhasespectrumCheckBox           matlab.ui.control.CheckBox
        PlotButton                      matlab.ui.control.Button
        GroupdelayCheckBox              matlab.ui.control.CheckBox
        OpeninnewfiguresCheckBox        matlab.ui.control.CheckBox
        NumberofpointsdisplayedEditFieldLabel  matlab.ui.control.Label
        NumberofpointsdisplayedEditField  matlab.ui.control.NumericEditField
        SamplingfrequencyHzEditFieldLabel  matlab.ui.control.Label
        SamplingfrequencyHzEditField    matlab.ui.control.NumericEditField
        TimetakentoplotgraphsEditFieldLabel  matlab.ui.control.Label
        TimetakentoplotgraphsEditField  matlab.ui.control.NumericEditField
        HarmonicanalysisButton          matlab.ui.control.Button
        ViewgraphsTab                   matlab.ui.container.Tab
        magPlotAx                       matlab.ui.control.UIAxes
        phasePlotAx                     matlab.ui.control.UIAxes
        groupPlotAx                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        minFreq = 0 % min freq to be displayed on graph 
        maxFreq = 1 % max freq to be displayed on graph
        samplingFreq
        harmonicApp
        fileName
    end
    
    methods (Access = private)
        % Calculate the rectangular logarithmic mean with spacing according to
        % Shivali's table (bark)
        function [log_means, freq_out] = rlogbark(app, freq, signal)
            idealNoPoints = [24 22 20 18 18 18 19 18 17 18 18 17 17 16 15 14 13 12 10 7 4 5 5 6 6];
            barkScaleBands = 25; % 24 + 1 for 15500 to 48000 Hz
            bark_freq = [100 200 300 400 510 630 770 920 1080 1270 1480 1720 2000 2320 2700 3150 3700 4400 5300 6400 7700 9500 12000 15500 48000];
            start_freq_indx = 1; % freq = 0
            end_freq_indx = zeros(barkScaleBands, 1); % freq indexes closest to bark freq
            for i = 1:barkScaleBands
                endf = find(round(freq) == bark_freq(i), 1);
                end_freq_indx(i) = endf;
            end
            band_spacing = zeros(barkScaleBands, 1); % # of indices between samples in each band
            for i = 1:barkScaleBands
                if i == 1
                    band_spacing(i) = round((end_freq_indx(i) - start_freq_indx)/idealNoPoints(i));
                else
                    band_spacing(i) = round((end_freq_indx(i) - end_freq_indx(i - 1))/idealNoPoints(i));
                end
            end
            freq_indx = start_freq_indx:band_spacing(1):(band_spacing(1)*(idealNoPoints(1)-1) + start_freq_indx); % samples in 1st band
            for n = 2:barkScaleBands
                freq_indx_temp = (end_freq_indx(n - 1)):band_spacing(n):(band_spacing(n)*(idealNoPoints(n) - 1) + end_freq_indx(n - 1));
                freq_indx_temp2 = horzcat(freq_indx, freq_indx_temp); % concatenate 
                if n < barkScaleBands
                    freq_indx = freq_indx_temp2;
                else
                    freq_indx_temp3 = horzcat(freq_indx_temp2, end_freq_indx(n)); % appends 48000 Hz index
                    freq_indx = freq_indx_temp3;
                end
            end
            avg_spacing = zeros(length(freq_indx) - 1, 1); % avg spacing between samples, used for avging, no avging on 1st sample
            
            avg_spacing_count = 0;
            for i = 1:length(idealNoPoints)
                if i == 1
                    for j = 2:idealNoPoints(i)
                        avg_spacing_count = avg_spacing_count + 1;
                        avg_spacing(avg_spacing_count) = band_spacing(i);
                    end
                else
                    for j = 1:idealNoPoints(i)
                        avg_spacing_count = avg_spacing_count + 1;
                        avg_spacing(avg_spacing_count) = band_spacing(i);
                    end
                end
            end
            log_means = zeros(length(freq_indx), 1);
            log_means(1) = signal(freq_indx(1));
            for n = 2:length(freq_indx)-1
                log_means(n) = 10*log10(sum(10.^(0.1*signal((freq_indx(n) - floor(avg_spacing(n - 1)/2)):(freq_indx(n) + floor(avg_spacing(n - 1)/2)))))/(avg_spacing(n - 1))); % logarithmic mean
            end
            % replace 48000 Hz avg with avg over smaller range
            log_means(end) = 10*log10(sum(10.^(0.1*signal((freq_indx(end) - 1000):(freq_indx(end) + 1000))))/2001);
            freq_out = freq(freq_indx);
        end
        % Calculate the rectangular geometric mean with spacing according to
        % Shivali's table (bark)
        function [geo_means, freq_out] = rgeobark(app, freq, signal)
            idealNoPoints = [24 22 20 18 18 18 19 18 17 18 18 17 17 16 15 14 13 12 10 7 4 5 5 6 6];
            barkScaleBands = 25; % 24 + 1 for 15500 to 48000 Hz
            bark_freq = [100 200 300 400 510 630 770 920 1080 1270 1480 1720 2000 2320 2700 3150 3700 4400 5300 6400 7700 9500 12000 15500 48000];
            start_freq_indx = 1; % freq = 0
            end_freq_indx = zeros(barkScaleBands, 1); % freq indexes closest to bark freq
            for i = 1:barkScaleBands
                endf = find(round(freq) == bark_freq(i), 1);
                end_freq_indx(i) = endf;
            end
            band_spacing = zeros(barkScaleBands, 1); % # of indices between samples in each band
            for i = 1:barkScaleBands
                if i == 1
                    band_spacing(i) = round((end_freq_indx(i) - start_freq_indx)/idealNoPoints(i));
                else
                    band_spacing(i) = round((end_freq_indx(i) - end_freq_indx(i - 1))/idealNoPoints(i));
                end
            end
            freq_indx = start_freq_indx:band_spacing(1):(band_spacing(1)*(idealNoPoints(1)-1) + start_freq_indx);
            for n = 2:barkScaleBands
                freq_indx_temp = (end_freq_indx(n - 1)):band_spacing(n):(band_spacing(n)*(idealNoPoints(n) - 1) + end_freq_indx(n - 1));
                freq_indx_temp2 = horzcat(freq_indx, freq_indx_temp); % concantenate
                if n < barkScaleBands
                    freq_indx = freq_indx_temp2;
                else
                    freq_indx_temp3 = horzcat(freq_indx_temp2, end_freq_indx(n)); % appends 48000 Hz index
                    freq_indx = freq_indx_temp3;
                end
            end
            avg_spacing = zeros(length(freq_indx) - 1, 1); % avg spacing between samples, used for avging, no avging on 1st sample
            
            avg_spacing_count = 0;
            for i = 1:length(idealNoPoints)
                if i == 1
                    for j = 2:idealNoPoints(i)
                        avg_spacing_count = avg_spacing_count + 1;
                        avg_spacing(avg_spacing_count) = band_spacing(i);
                    end
                else
                    for j = 1:idealNoPoints(i)
                        avg_spacing_count = avg_spacing_count + 1;
                        avg_spacing(avg_spacing_count) = band_spacing(i);
                    end
                end
            end
            geo_means = zeros(length(freq_indx), 1);
            geo_means(1) = signal(freq_indx(1));
            for n = 2:length(freq_indx)-1
                geo_means(n) = mean(signal((freq_indx(n) - floor(avg_spacing(n - 1)/2)):(freq_indx(n) + floor(avg_spacing(n - 1)/2)))); % geometric mean
            end
            % replace 48000 Hz avg with avg over smaller range
            geo_means(end) = mean(signal((freq_indx(end) - 1000):(freq_indx(end) + 1000)));
            freq_out = freq(freq_indx);
        end
        
        % Calculate the rectangular logarithmic mean with spacing according to
        % ERB scale
        function [log_means, freq_out] = rlogerb(app, freq, signal)
            freq_max_indx = find(round(freq) == 48000, 1);
            erb = hz2erb(freq(1:freq_max_indx));
            no_bands = round(erb(end));
            sample_indx = zeros(no_bands, 1);
            for n = 1:no_bands-1
                sample_indx(n) = find(round(erb) == n, 1);
            end
            sample_indx(end) = length(erb);
            % In the smallest band can tell about 1 Hz apart
            % Use same number of samples in each band
            samplesPerBand = round(freq(sample_indx(1)) - freq(1));
            band_spacing = zeros(no_bands, 1);
            freq_range = zeros(no_bands, 1);
            for n = 1:no_bands
                if n == 1
                    freq_range(n) = sample_indx(n) - 1;
                else
                    freq_range(n) = sample_indx(n) - sample_indx(n - 1);
                end
                band_spacing(n) = round(freq_range(n)/samplesPerBand);
            end
            freq_indx = (1:band_spacing(1):(band_spacing(1)*(samplesPerBand - 1) + 1));
            for n = 2:no_bands
                freq_indx_temp = ((sample_indx(n - 1)):band_spacing(n):(band_spacing(n)*(samplesPerBand-1) + sample_indx(n - 1)));
                freq_indx_temp2 = horzcat(freq_indx, freq_indx_temp); % concatenate
                if n < no_bands
                    freq_indx = freq_indx_temp2;
                else
                    freq_indx_temp3 = horzcat(freq_indx_temp2, sample_indx(n)); % appends 48000 Hz index
                    freq_indx = freq_indx_temp3;
                end
            end
            avg_spacing = zeros(length(freq_indx) - 1, 1); % avg spacing between samples, used for avging, no avging on 1st sample
            
            avg_spacing_count = 0;
            for i = 1:no_bands
                if i == 1
                    for j = 2:samplesPerBand
                        avg_spacing_count = avg_spacing_count + 1;
                        avg_spacing(avg_spacing_count) = band_spacing(i);
                    end
                else
                    for j = 1:samplesPerBand
                        avg_spacing_count = avg_spacing_count + 1;
                        avg_spacing(avg_spacing_count) = band_spacing(i);
                    end
                end
            end
            log_means = zeros(length(freq_indx), 1);
            log_means(1) = signal(freq_indx(1));
            for n = 2:length(freq_indx)-1
                log_means(n) = 10*log10(sum(10.^(0.1*signal((freq_indx(n) - floor(avg_spacing(n - 1)/2)):(freq_indx(n) + floor(avg_spacing(n - 1)/2)))))/(avg_spacing(n - 1))); % logarithmic mean
            end
            % replace 48000 Hz avg with avg over smaller range
            log_means(end) = 10*log10(sum(10.^(0.1*signal((freq_indx(end) - 1000):(freq_indx(end) + 1000))))/2001);
            freq_out = freq(freq_indx);
        end
        
        % Calculate the rectangular geometric mean with spacing according to
        % ERB scale
        function [geo_means, freq_out] = rgeoerb(app, freq, signal)
            freq_max_indx = find(round(freq) == 48000, 1);
            erb = hz2erb(freq(1:freq_max_indx));
            no_bands = round(erb(end));
            sample_indx = zeros(no_bands, 1);
            for n = 1:no_bands-1
                sample_indx(n) = find(round(erb) == n, 1);
            end
            sample_indx(end) = length(erb);
            % In the smallest band can tell about 1 Hz apart
            % Use same number of samples in each band
            samplesPerBand = round(freq(sample_indx(1)) - freq(1));
            band_spacing = zeros(no_bands, 1);
            freq_range = zeros(no_bands, 1);
            for n = 1:no_bands
                if n == 1
                    freq_range(n) = sample_indx(n) - 1;
                else
                    freq_range(n) = sample_indx(n) - sample_indx(n - 1);
                end
                band_spacing(n) = round(freq_range(n)/samplesPerBand);
            end
            freq_indx = (1:band_spacing(1):(band_spacing(1)*(samplesPerBand - 1) + 1));
            for n = 2:no_bands
                freq_indx_temp = ((sample_indx(n - 1)):band_spacing(n):(band_spacing(n)*(samplesPerBand-1) + sample_indx(n - 1)));
                freq_indx_temp2 = horzcat(freq_indx, freq_indx_temp); % concatenate
                if n < no_bands
                    freq_indx = freq_indx_temp2;
                else
                    freq_indx_temp3 = horzcat(freq_indx_temp2, sample_indx(n)); % appends 48000 Hz index
                    freq_indx = freq_indx_temp3;
                end
            end
            avg_spacing = zeros(length(freq_indx) - 1, 1); % avg spacing between samples, used for avging, no avging on 1st sample
            
            avg_spacing_count = 0;
            for i = 1:no_bands
                if i == 1
                    for j = 2:samplesPerBand
                        avg_spacing_count = avg_spacing_count + 1;
                        avg_spacing(avg_spacing_count) = band_spacing(i);
                    end
                else
                    for j = 1:samplesPerBand
                        avg_spacing_count = avg_spacing_count + 1;
                        avg_spacing(avg_spacing_count) = band_spacing(i);
                    end
                end
            end
            geo_means = zeros(length(freq_indx), 1);
            geo_means(1) = signal(freq_indx(1));
            for n = 2:length(freq_indx)-1
                geo_means(n) = mean(signal((freq_indx(n) - floor(avg_spacing(n - 1)/2)):(freq_indx(n) + floor(avg_spacing(n - 1)/2)))); % geometric mean
            end
            % replace 48000 Hz avg with avg over smaller range
            geo_means(end) = mean(signal((freq_indx(end) - 1000):(freq_indx(end) + 1000)));
            freq_out = freq(freq_indx);
        end
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            [file,path] = uigetfile('*'); % open file
            if isequal(file,0)
            else
               app.FilenameEditField.Value = fullfile(path, file);
            end
            drawnow; 
            figure(app.UIFigure) % refocus app (otherwise minimised)
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.minFreq = app.MinimumfrequencyHzEditField.Value;
            app.maxFreq = app.MaximumfrequencyHzEditField.Value;
            try
                [data, fs] = audioread(app.FilenameEditField.Value);
                app.fileName = app.FilenameEditField.Value;
                app.samplingFreq = fs;
                info = audioinfo(app.FilenameEditField.Value);
                NoSamples = info.TotalSamples; % number of samples in file
                %app.NumberofpointsdisplayedEditField.Value = NoSamples;
                app.SamplingfrequencyHzEditField.Value = fs;
                transform = fft(data); % fast fourier transform of signal
                magspec = 20*log10(abs(transform)); % magnitude spectrum
                freq = fs*(0:NoSamples-1)/NoSamples; % frequency axis
                phase = unwrap(angle(transform));
                phasedeg = phase*(180/pi);
                group_delay = -diff(phase')./(diff(freq)*360);
                
                if app.ShowrectangularmeansmoothedsignalblackCheckBox.Value == 1
                    if app.RectangularsmoothingscaleDropDown.Value == '1'
                        [mag_rlog, f_rlog] = rlogbark(app, freq, magspec);
                        [phasedeg_rlog, f_rlog] = rgeobark(app, freq, phasedeg);
                        [group_delay_rlog1, f_rlog] = rgeobark(app, freq, group_delay);
                        group_delay_rlog = group_delay_rlog1(2:end);
                    else
                        [mag_rlog, f_rlog] = rlogerb(app, freq, magspec);
                        [phasedeg_rlog, f_rlog] = rgeoerb(app, freq, phasedeg);
                        [group_delay_rlog1, f_rlog] = rgeoerb(app, freq, group_delay);
                        group_delay_rlog = group_delay_rlog1(2:end);
                    end
                end
                cla(app.magPlotAx)
                cla(app.phasePlotAx)
                cla(app.groupPlotAx)
                if app.ShowunsmoothedsignalgreyCheckBox.Value == 1
                    app.NumberofpointsdisplayedEditField.Value = length(freq);
                elseif app.ShowrectangularmeansmoothedsignalblackCheckBox.Value == 1
                    app.NumberofpointsdisplayedEditField.Value = length(f_rlog);
                else
                    app.NumberofpointsdisplayedEditField.Value = 0;
                end
                app.magPlotAx.NextPlot = 'add';
                app.phasePlotAx.NextPlot = 'add';
                app.groupPlotAx.NextPlot = 'add';
                tic; % start stopwatch for plotting graph
                if app.MagnitudespectrumCheckBox.Value == 1
                    magmax = max(magspec);
                    app.magPlotAx.XLim = [app.minFreq app.maxFreq]; % range of x axis
                    if app.OpeninnewfiguresCheckBox.Value == 1
                        magfig = uifigure;
                        magAx = uiaxes(magfig, 'Position',[10 10 520 400]);
                        magAx.XScale = 'log';
                        magAx.XLim = [app.minFreq app.maxFreq]; % range of x axis
                        magAx.XGrid = 'on';
                        magAx.YGrid = 'on';
                        magAx.Title.String = 'Magnitude spectrum';
                        magAx.YLabel.String = 'Magnitude (dB)';
                        magAx.XLabel.String = 'Frequency (Hz)';
                        magAx.NextPlot = 'add';
                    end
                    if app.ShowunsmoothedsignalgreyCheckBox.Value == 1
                        plot(app.magPlotAx, freq, magspec - magmax, 'Color',[0.7,0.7,0.7])
                        if app.OpeninnewfiguresCheckBox.Value == 1
                            plot(magAx, freq, magspec - magmax, 'Color',[0.7,0.7,0.7], 'DisplayName','Unsmoothed')
                        end
                    end
                    if app.ShowrectangularmeansmoothedsignalblackCheckBox.Value == 1
                        %magmax_rlog = max(mag_rlog);
                        plot(app.magPlotAx, f_rlog, mag_rlog - magmax, 'k')
                        if app.OpeninnewfiguresCheckBox.Value == 1
                            plot(magAx, f_rlog, mag_rlog - magmax, 'k', 'DisplayName','Rectangular mean')
                        end
                    end
                    if app.OpeninnewfiguresCheckBox.Value == 1
                        legend(magAx, 'Location', 'southwest')
                    end
                end
                if app.PhasespectrumCheckBox.Value == 1
                    app.phasePlotAx.XLim = [app.minFreq app.maxFreq]; % range of x axis
                    if app.OpeninnewfiguresCheckBox.Value == 1
                        if (app.ShowunsmoothedsignalgreyCheckBox.Value || app.ShowrectangularmeansmoothedsignalblackCheckBox.Value)
                            phasefig = uifigure;
                            phaseAx = uiaxes(phasefig, 'Position',[10 10 520 400]);
                            phaseAx.XScale = 'log';
                            phaseAx.XLim = [app.minFreq app.maxFreq]; % range of x axis
                            phaseAx.XGrid = 'on';
                            phaseAx.YGrid = 'on';
                            phaseAx.Title.String = 'Phase spectrum';
                            phaseAx.YLabel.String = 'Phase (degrees)';
                            phaseAx.XLabel.String = 'Frequency (Hz)';
                            phaseAx.NextPlot = 'add';
                        end
                    end
                    if app.ShowunsmoothedsignalgreyCheckBox.Value == 1
                        plot(app.phasePlotAx, freq, phasedeg, 'Color',[0.7,0.7,0.7])
                        if app.OpeninnewfiguresCheckBox.Value == 1
                            plot(phaseAx, freq, phasedeg, 'Color',[0.7,0.7,0.7], 'DisplayName','Unsmoothed')
                        end
                    end
                    if app.ShowrectangularmeansmoothedsignalblackCheckBox.Value == 1
                        plot(app.phasePlotAx, f_rlog, phasedeg_rlog, 'k')
                        if app.OpeninnewfiguresCheckBox.Value == 1
                            plot(phaseAx, f_rlog, phasedeg_rlog, 'k', 'DisplayName','Rectangular mean')
                        end
                    end
                    if app.OpeninnewfiguresCheckBox.Value == 1
                        legend(phaseAx, 'Location', 'southwest')
                    end
                end
                if app.GroupdelayCheckBox.Value == 1
                    app.groupPlotAx.XLim = [app.minFreq app.maxFreq]; % range of x axis
                    if app.OpeninnewfiguresCheckBox.Value == 1
                        if (app.ShowunsmoothedsignalgreyCheckBox.Value || app.ShowrectangularmeansmoothedsignalblackCheckBox)
                            groupfig = uifigure;
                            groupAx = uiaxes(groupfig, 'Position',[10 10 520 400]);
                            groupAx.XScale = 'log';
                            groupAx.XLim = [app.minFreq app.maxFreq]; % range of x axis
                            groupAx.XGrid = 'on';
                            groupAx.YGrid = 'on';
                            groupAx.Title.String = 'Group delay';
                            groupAx.YLabel.String = 'Group delay (s)';
                            groupAx.XLabel.String = 'Frequency (Hz)';
                            groupAx.NextPlot = 'add';
                        end
                    end
                    if app.ShowunsmoothedsignalgreyCheckBox.Value == 1
                        plot(app.groupPlotAx, freq(2:end), group_delay, 'Color',[0.7,0.7,0.7])
                        if app.OpeninnewfiguresCheckBox.Value == 1
                            plot(groupAx, freq(2:end), group_delay, 'Color',[0.7,0.7,0.7], 'DisplayName','Unsmoothed')
                        end
                    end
                    if app.ShowrectangularmeansmoothedsignalblackCheckBox.Value == 1
                        plot(app.groupPlotAx, f_rlog(2:end), group_delay_rlog, 'k')
                        if app.OpeninnewfiguresCheckBox.Value == 1
                            plot(groupAx, f_rlog(2:end), group_delay_rlog, 'k', 'DisplayName','Rectangular mean')
                        end
                    end
                    if app.OpeninnewfiguresCheckBox.Value == 1
                        legend(groupAx, 'Location', 'southwest')
                    end
                end
                app.TimetakentoplotgraphsEditField.Value = toc; % time taken
            catch % if audioread doesn't work
                app.FilenameEditField.Value = 'Enter a valid file name.';
                % outputted values returned to defaults (0):
                app.NumberofpointsdisplayedEditField.Value = 0;
                app.TimetakentoplotgraphsEditField.Value = 0;
                app.SamplingfrequencyHzEditField.Value = 0;
            end
        end

        % Button pushed function: HarmonicanalysisButton
        function HarmonicanalysisButtonPushed(app, event)
            app.harmonicApp = harmonicdistortionapp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.SelectOptionsTab = uitab(app.TabGroup2);
            app.SelectOptionsTab.Title = 'Select Options';
            app.FilenameEditFieldLabel = uilabel(app.SelectOptionsTab);
            app.FilenameEditFieldLabel.Tooltip = {''};
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.SelectOptionsTab, 'text');
            app.FilenameEditField.Tooltip = {'Enter an audio file name, including the file format at the end, e.g. .wav '};
            app.FilenameEditField.Value = 'task1file.wav';
            app.SelectfileButton = uibutton(app.SelectOptionsTab, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.ORLabel = uilabel(app.SelectOptionsTab);
            app.ORLabel.Text = 'OR';
            app.MinimumfrequencyHzEditFieldLabel = uilabel(app.SelectOptionsTab);
            app.MinimumfrequencyHzEditFieldLabel.Text = 'Minimum frequency (Hz)';
            app.MinimumfrequencyHzEditField = uieditfield(app.SelectOptionsTab, 'numeric');
            app.MinimumfrequencyHzEditField.Limits = [0 Inf];
            app.MinimumfrequencyHzEditField.ValueDisplayFormat = '%.0f';
            app.MinimumfrequencyHzEditField.Tooltip = {'Minimum frequency value to be displayed'};
            app.MinimumfrequencyHzEditField.Value = 10;
            app.MaximumfrequencyHzEditFieldLabel = uilabel(app.SelectOptionsTab);
            app.MaximumfrequencyHzEditFieldLabel.Text = 'Maximum frequency (Hz)';
            app.MaximumfrequencyHzEditField = uieditfield(app.SelectOptionsTab, 'numeric');
            app.MaximumfrequencyHzEditField.Limits = [0 Inf];
            app.MaximumfrequencyHzEditField.ValueDisplayFormat = '%.0f';
            app.MaximumfrequencyHzEditField.Tooltip = {'Maximum frequency value to be displayed'};
            app.MaximumfrequencyHzEditField.Value = 40000;
            app.FrequencydomainsmoothingPanel = uipanel(app.SelectOptionsTab);
            app.FrequencydomainsmoothingPanel.Title = 'Frequency domain smoothing';
            app.ShowunsmoothedsignalgreyCheckBox = uicheckbox(app.FrequencydomainsmoothingPanel);
            app.ShowunsmoothedsignalgreyCheckBox.Text = 'Show unsmoothed signal (grey)';
            app.ShowunsmoothedsignalgreyCheckBox.Value = true;
            app.ShowrectangularmeansmoothedsignalblackCheckBox = uicheckbox(app.FrequencydomainsmoothingPanel);
            app.ShowrectangularmeansmoothedsignalblackCheckBox.Text = 'Show rectangular mean smoothed signal (black)';
            app.ShowrectangularmeansmoothedsignalblackCheckBox.Value = true;
            app.LogmeansmoothedmagnitudeLabel = uilabel(app.FrequencydomainsmoothingPanel);
            app.LogmeansmoothedmagnitudeLabel.Text = '- Log-mean smoothed magnitude';
            app.GeometricmeansmoothedphaseandgroupdelayLabel = uilabel(app.FrequencydomainsmoothingPanel);
            app.GeometricmeansmoothedphaseandgroupdelayLabel.Text = '- Geometric mean smoothed phase and group delay';
            app.RectangularsmoothingscaleDropDownLabel = uilabel(app.FrequencydomainsmoothingPanel);
            app.RectangularsmoothingscaleDropDownLabel.Text = 'Rectangular smoothing scale';
            app.RectangularsmoothingscaleDropDown = uidropdown(app.FrequencydomainsmoothingPanel);
            app.RectangularsmoothingscaleDropDown.Items = {'Bark Scale', 'ERB Scale'};
            app.RectangularsmoothingscaleDropDown.ItemsData = {'1', '2'};
            app.RectangularsmoothingscaleDropDown.Value = '1';
            app.PlottingPanel = uipanel(app.SelectOptionsTab);
            app.PlottingPanel.Title = 'Plotting';
            app.MagnitudespectrumCheckBox = uicheckbox(app.PlottingPanel);
            app.MagnitudespectrumCheckBox.Text = 'Magnitude spectrum';
            app.MagnitudespectrumCheckBox.Value = true;
            app.PhasespectrumCheckBox = uicheckbox(app.PlottingPanel);
            app.PhasespectrumCheckBox.Text = 'Phase spectrum';
            app.PhasespectrumCheckBox.Value = true;
            app.PlotButton = uibutton(app.PlottingPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.GroupdelayCheckBox = uicheckbox(app.PlottingPanel);
            app.GroupdelayCheckBox.Text = 'Group delay';
            app.GroupdelayCheckBox.Value = true;
            app.OpeninnewfiguresCheckBox = uicheckbox(app.PlottingPanel);
            app.OpeninnewfiguresCheckBox.Text = 'Open in new figures';
            app.NumberofpointsdisplayedEditFieldLabel = uilabel(app.SelectOptionsTab);
            app.NumberofpointsdisplayedEditFieldLabel.Text = 'Number of points displayed';
            app.NumberofpointsdisplayedEditField = uieditfield(app.SelectOptionsTab, 'numeric');
            app.NumberofpointsdisplayedEditField.ValueDisplayFormat = '%.0f';
            app.NumberofpointsdisplayedEditField.Editable = 'off';
            app.SamplingfrequencyHzEditFieldLabel = uilabel(app.SelectOptionsTab);
            app.SamplingfrequencyHzEditFieldLabel.Text = 'Sampling frequency (Hz)';
            app.SamplingfrequencyHzEditField = uieditfield(app.SelectOptionsTab, 'numeric');
            app.SamplingfrequencyHzEditField.ValueDisplayFormat = '%.0f';
            app.SamplingfrequencyHzEditField.Editable = 'off';
            app.TimetakentoplotgraphsEditFieldLabel = uilabel(app.SelectOptionsTab);
            app.TimetakentoplotgraphsEditFieldLabel.Text = 'Time taken to plot graph (s)';
            app.TimetakentoplotgraphsEditField = uieditfield(app.SelectOptionsTab, 'numeric');
            app.TimetakentoplotgraphsEditField.ValueDisplayFormat = '%.3f';
            app.TimetakentoplotgraphsEditField.Editable = 'off';
            app.HarmonicanalysisButton = uibutton(app.SelectOptionsTab, 'push');
            app.HarmonicanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @HarmonicanalysisButtonPushed, true);
            app.HarmonicanalysisButton.Text = 'Harmonic analysis';
            app.ViewgraphsTab = uitab(app.TabGroup2);
            app.ViewgraphsTab.Title = 'View graphs';
            app.magPlotAx = uiaxes(app.ViewgraphsTab);
            title(app.magPlotAx, 'Magnitude')
            xlabel(app.magPlotAx, 'frequency (Hz)')
            ylabel(app.magPlotAx, 'Magnitude (dB)')
            app.magPlotAx.XLim = [10 40000];
            app.magPlotAx.YLim = [-150 0];
            app.magPlotAx.XScale = 'log';
            app.phasePlotAx = uiaxes(app.ViewgraphsTab);
            title(app.phasePlotAx, 'Phase')
            xlabel(app.phasePlotAx, 'frequency (Hz)')
            ylabel(app.phasePlotAx, 'Phase (deg)')
            app.phasePlotAx.XLim = [10 40000];
            app.phasePlotAx.XScale = 'log';
            app.groupPlotAx = uiaxes(app.ViewgraphsTab);
            title(app.groupPlotAx, 'Group Delay')
            xlabel(app.groupPlotAx, 'frequency (Hz)')
            ylabel(app.groupPlotAx, 'Group delay (s)')
            app.groupPlotAx.XLim = [10 40000];
            app.groupPlotAx.XScale = 'log';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = updatedapp_task1to3_rectangularonly_with_harmonics

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end