classdef task1and2withgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        MinimumfrequencyHzEditFieldLabel  matlab.ui.control.Label
        MinimumfrequencyHzEditField     matlab.ui.control.NumericEditField
        MaximumfrequencyHzEditFieldLabel  matlab.ui.control.Label
        MaximumfrequencyHzEditField     matlab.ui.control.NumericEditField
        SelectfileButton                matlab.ui.control.Button
        ORLabel                         matlab.ui.control.Label
        NumberofpointsdisplayedEditFieldLabel  matlab.ui.control.Label
        NumberofpointsdisplayedEditField  matlab.ui.control.NumericEditField
        TimetakentoplotgraphsEditFieldLabel  matlab.ui.control.Label
        TimetakentoplotgraphsEditField  matlab.ui.control.NumericEditField
        FrequencydomainsmoothingPanel   matlab.ui.container.Panel
        SmoothingSwitchLabel            matlab.ui.control.Label
        SmoothingSwitch                 matlab.ui.control.Switch
        TabGroup                        matlab.ui.container.TabGroup
        OctaveTab                       matlab.ui.container.Tab
        BaseDropDownLabel               matlab.ui.control.Label
        BaseDropDown                    matlab.ui.control.DropDown
        TriangularTab                   matlab.ui.container.Tab
        DownsamplingDropDownLabel       matlab.ui.control.Label
        DownsamplingDropDown            matlab.ui.control.DropDown
        WindowsizesamplesEditFieldLabel  matlab.ui.control.Label
        WindowsizesamplesEditField      matlab.ui.control.NumericEditField
        SGTab                           matlab.ui.container.Tab
        DownsamplingDropDown_2Label     matlab.ui.control.Label
        DownsamplingDropDown_2          matlab.ui.control.DropDown
        ODDwindowsizesamplesEditFieldLabel  matlab.ui.control.Label
        ODDwindowsizesamplesEditField   matlab.ui.control.NumericEditField
        OrderlessthanwindowsizeEditFieldLabel  matlab.ui.control.Label
        OrderlessthanwindowsizeEditField  matlab.ui.control.NumericEditField
        FiltertypeDropDownLabel         matlab.ui.control.Label
        FiltertypeDropDown              matlab.ui.control.DropDown
        SamplingfrequencyHzEditFieldLabel  matlab.ui.control.Label
        SamplingfrequencyHzEditField    matlab.ui.control.NumericEditField
        PlottingPanel                   matlab.ui.container.Panel
        MagnitudespectrumCheckBox       matlab.ui.control.CheckBox
        PhasespectrumunwrappedCheckBox  matlab.ui.control.CheckBox
        PlotButton                      matlab.ui.control.Button
        GroupdelayCheckBox              matlab.ui.control.CheckBox
        TimetakentoapplyfiltersEditFieldLabel  matlab.ui.control.Label
        TimetakentoapplyfiltersEditField  matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        minFreq = 0 % min freq to be displayed on graph 
        maxFreq = 1 % max freq to be displayed on graph
        samplingFreq
    end
   
    methods (Access = private)
        
        % 1/3 Octave Smoothing
        function [filt_sig, fcenter] = Octave(app, amp, f, base)
            if base == 2
                fcenter  = 10^3 * (2 .^ ([-18:16]/3));
                fd = 2^(1/6);
            else
                fcenter = 10.^(0.1.*[12:46]);
                fd = 10^0.05;
            end
            fupper = fcenter * fd;
            flower = fcenter / fd;
            filt_sig = zeros(length(fcenter), 1);
            for i=1:length(fcenter)
                filt_sig(i) =  10*log(sum(10.^(0.1*amp(find(f >= flower(i) & f <= fupper(i)))))/length(find(f >= flower(i) & f <= fupper(i)))); % logarithmic mean
            end
        end
        
        % Triangular smooth
        function tsmoothed = triangle(app, Y, smoothwidth)
            w=round(smoothwidth);
            SumPoints=sum(Y(1:w));
            s=zeros(size(Y));
            halfw=round(w/2);
            L=length(Y);
            for k=1:L-w
               s(k+halfw-1)=SumPoints;
               SumPoints=SumPoints-Y(k);
               SumPoints=SumPoints+Y(k+w);
            end
            s(k+halfw)=sum(Y(L-w+1:L));
            tsmoothed=s./w;
        end
        
        % ERB Downsampling
        function [ERB_freq, ERB_mag, ERB_phase, ERB_group_delay] = ERBDownsample(app, freq, mag, phase, group_delay)
            erb = hz2erb(freq);
            no_bands = round(erb(end));
            sample_indx = zeros(no_bands, 1);
            for n = 1:no_bands-1
                sample_indx(n) = find(round(erb) == n, 1);
            end
            sample_indx(end) = length(erb);
            
            % In the smallest band can tell about 1 Hz apart - for safety do 0.5 Hz
            % Use same number of samples in each band
            samplesPerBand = round(freq(sample_indx(1)) - freq(1))*2;
            band_spacing = zeros(no_bands, 1);
            freq_range = zeros(no_bands, 1);
            for n = 1:no_bands
                if n == 1
                    freq_range(n) = sample_indx(n) - 1;
                else
                    freq_range(n) = sample_indx(n) - sample_indx(n - 1);
                end
                band_spacing(n) = round(freq_range(n)/samplesPerBand);
            end
            ERB_freq = erb_space(app, freq, no_bands, samplesPerBand, band_spacing, sample_indx);
            ERB_mag = erb_space(app, mag', no_bands, samplesPerBand, band_spacing, sample_indx);
            ERB_phase = erb_space(app, phase', no_bands, samplesPerBand, band_spacing, sample_indx);
            ERB_group_delay1 = erb_space(app, group_delay, no_bands, samplesPerBand, band_spacing, sample_indx);
            ERB_group_delay = ERB_group_delay1(2:end);
        end
        
        function new_signal = erb_space(app, signal, no_bands, samplesPerBand, band_spacing, sample_indx)
        %ERB_SPACE Space the signal so that there are the same number of samples in
        %each ERB
        new_signal = signal(1:band_spacing(1):(band_spacing(1)*samplesPerBand));
        for n = 2:no_bands
            new_signal_temp = signal((sample_indx(n - 1)):band_spacing(n):(band_spacing(n)*samplesPerBand + sample_indx(n - 1) - 1));
            new_signal_temp2 = horzcat(new_signal, new_signal_temp);
            new_signal = new_signal_temp2;
        end
        end


        % Bark Downsampling
        function [Bark_freq, Bark_mag, Bark_phase, Bark_group_delay] = BarkDownsample(app, freq, mag, phase, group_delay)
            barkScaleBands = 25; % 24 + 1 for rest of band
            bark_freq = [100 200 300 400 510 630 770 920 1080 1270 1480 1720 2000 2320 2700 3150 3700 4400 5300 6400 7700 9500 12000 15500 48000];;
            start_freq_indx = 1;
            end_freq_indx = zeros(barkScaleBands, 1);
            for i = 1:barkScaleBands
                endf = find(round(freq) == bark_freq(i), 1);
                end_freq_indx(i) = endf;
            end
            % extra 6 for rest of band
            idealNoPoints = [24 22 20 18 18 18 19 18 17 18 18 17 17 16 15 14 13 12 10 7 4 5 5 6 6];
            
            band_spacing = zeros(barkScaleBands, 1);
            for i = 1:barkScaleBands
                if i == 1
                    band_spacing(i) = round((end_freq_indx(i))/idealNoPoints(i));
                else
                    band_spacing(i) = round((end_freq_indx(i) - end_freq_indx(i - 1))/idealNoPoints(i));
                end
            end
            Bark_freq = bark_space(app, freq, barkScaleBands, start_freq_indx, end_freq_indx, idealNoPoints, band_spacing);
            Bark_mag = bark_space(app, mag', barkScaleBands, start_freq_indx, end_freq_indx, idealNoPoints, band_spacing);
            Bark_phase = bark_space(app, phase', barkScaleBands, start_freq_indx, end_freq_indx, idealNoPoints, band_spacing);
            %group_delay1 = horzcat(group_delay(1), group_delay);
            Bark_group_delay1 = bark_space(app, group_delay, barkScaleBands, start_freq_indx, end_freq_indx, idealNoPoints, band_spacing);
            Bark_group_delay = Bark_group_delay1(2:end);
        end
        
        function new_signal = bark_space(app, signal, no_bands, start_freq_indx, end_freq_indx, idealNoPoints, band_spacing)
        % BARK_SPACE downsamples the signal in accordance with the bark scale
        new_signal = signal(start_freq_indx:band_spacing(1):(band_spacing(1)*idealNoPoints(1)));
        for n = 2:no_bands
            new_signal_temp = signal((end_freq_indx(n - 1)):band_spacing(n):(band_spacing(n)*idealNoPoints(n) + end_freq_indx(n - 1) - 1));
            new_signal_temp2 = horzcat(new_signal, new_signal_temp);
            new_signal = new_signal_temp2;
        end
        end

    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            [file,path] = uigetfile('*'); % open file
            if isequal(file,0)
            else
               app.FilenameEditField.Value = fullfile(path, file);
            end
            drawnow; 
            figure(app.UIFigure) % refocus app (otherwise minimised)
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.minFreq = app.MinimumfrequencyHzEditField.Value;
            app.maxFreq = app.MaximumfrequencyHzEditField.Value;
            windowSizeT = round(abs(app.WindowsizesamplesEditField.Value));
            if windowSizeT < 2
                windowSizeT = 2;
            end
            windowSizeSG = round(abs(app.ODDwindowsizesamplesEditField.Value));
            orderSG = round(abs(app.OrderlessthanwindowsizeEditField.Value));
            if orderSG < 2
                orderSG = 2;
            end
            if windowSizeSG <= orderSG
                windowSizeSG = orderSG + 1;
            end
            if mod(windowSizeSG, 2) == 0
                windowSizeSG = windowSizeSG + 1;
            end
            base = app.BaseDropDown.Value;
            %try
                [data, fs] = audioread(app.FilenameEditField.Value);
                app.samplingFreq = fs;
                info = audioinfo(app.FilenameEditField.Value);
                NoSamples = info.TotalSamples; % number of samples in file
                %app.NumberofpointsdisplayedEditField.Value = NoSamples;
                app.SamplingfrequencyHzEditField.Value = fs;
                if app.SmoothingSwitch.Value == '1' % filter on
                    if app.FiltertypeDropDown.Value == '1' % Octave filter
                        Nfft = 2^nextpow2(length(data));
                        ydft = fft(data, Nfft);
                        magspec = 20*log(abs(ydft)); 
                        freq = fs*((0:Nfft-1)/Nfft);
                        phase = unwrap(angle(ydft));
                        phasedeg = phase*(180/pi);
                        group_delay = -diff(phase')./(diff(freq)*360);
                        [filtMag, fcenter] = Octave(app, magspec, freq, base);
                        magspec = filtMag;
                        %filtPhase = Octave(app, phasedeg, base);
                        %phasedeg = filtPhase;
                        %filtGroupDelay = Octave(app, group_delay, base);
                        %group_delay = filtGroupDelay;
                        freq1 = freq;
                    elseif app.FiltertypeDropDown.Value == '2' % Triangular
                        transform = fft(data); % fast fourier transform of signal
                        magspec = 20*log10(abs(transform)); % magnitude spectrum
                        freq = fs*(0:NoSamples-1)/NoSamples; % frequency axis
                        phase = unwrap(angle(transform));
                        phasedeg = phase*(180/pi);
                        group_delay = -diff(phase')./(diff(freq)*360);
                        if app.DownsamplingDropDown.Value == '1'
                            filtMag = triangle(app, magspec, windowSizeT);
                            magspec = filtMag;
                            filtPhase = triangle(app, phasedeg, windowSizeT);
                            phasedeg = filtPhase;
                            filtGroupDelay = triangle(app, group_delay, windowSizeT);
                            group_delay = filtGroupDelay;
                            freq1 = freq;
                        %elseif app.DownsamplingDropDown.Value == '2'
                            %[freq1, ERB_mag, ERB_phasedeg, ERB_group_delay] = ERBDownsample(app, freq, magspec, phasedeg, group_delay);
                            %filtMag = triangle(app, ERB_mag, windowSizeT);
                            %magspec = filtMag;
                            %filtPhase = triangle(app, ERB_phasedeg, windowSizeT);
                            %phasedeg = filtPhase;
                            %filtGroupDelay = triangle(app, ERB_group_delay, windowSizeT);
                            %group_delay = filtGroupDelay;
                        else 
                            [freq1, Bark_mag, Bark_phasedeg, Bark_group_delay] = BarkDownsample(app, freq, magspec, phasedeg, group_delay);
                            filtMag = triangle(app, Bark_mag, windowSizeT);
                            magspec = filtMag;
                            filtPhase = triangle(app, Bark_phasedeg, windowSizeT);
                            phasedeg = filtPhase;
                            filtGroupDelay = triangle(app, Bark_group_delay, windowSizeT);
                            group_delay = filtGroupDelay;
                        end
                    else % Savitzky-Golay
                        transform = fft(data); % fast fourier transform of signal
                        magspec = 20*log10(abs(transform)); % magnitude spectrum
                        freq = fs*(0:NoSamples-1)/NoSamples; % frequency axis
                        phase = unwrap(angle(transform));
                        phasedeg = phase*(180/pi);
                        group_delay = -diff(phase')./(diff(freq)*360);
                        if app.DownsamplingDropDown_2.Value == '1'
                            filtMag = sgolayfilt(magspec, orderSG, windowSizeSG);
                            magspec = filtMag;
                            filtPhase = sgolayfilt(phasedeg, orderSG, windowSizeSG);
                            phasedeg = filtPhase;
                            filtGroupDelay= sgolayfilt(group_delay, orderSG, windowSizeSG);
                            group_delay = filtGroupDelay;
                            freq1 = freq;
                        %elseif app.DownsamplingDropDown_2.Value == '2'
                            %[freq1, ERB_mag, ERB_phasedeg, ERB_group_delay] = ERBDownsample(app, freq, magspec, phasedeg, group_delay);
                            %filtMag = sgolayfilt(ERB_mag, orderSG, windowSizeSG);
                            %magspec = filtMag;
                            %filtPhase = sgolayfilt(ERB_phasedeg, orderSG, windowSizeSG);
                            %phasedeg = filtPhase;
                            %filtGroupDelay= sgolayfilt(ERB_group_delay, orderSG, windowSizeSG);
                            %group_delay = filtGroupDelay;
                        else
                            [freq1, Bark_mag, Bark_phasedeg, Bark_group_delay] = BarkDownsample(app, freq, magspec, phasedeg, group_delay);
                            filtMag = sgolayfilt(Bark_mag, orderSG, windowSizeSG);
                            magspec = filtMag;
                            filtPhase = sgolayfilt(Bark_phasedeg, orderSG, windowSizeSG);
                            phasedeg = filtPhase;
                            filtGroupDelay= sgolayfilt(Bark_group_delay, orderSG, windowSizeSG);
                            group_delay = filtGroupDelay;
                        end
                    end
                else % no filtering
                    transform = fft(data); % fast fourier transform of signal
                    magspec = 20*log10(abs(transform)); % magnitude spectrum
                    freq = fs*(0:NoSamples-1)/NoSamples; % frequency axis
                    phase = unwrap(angle(transform));
                    phasedeg = phase*(180/pi);
                    group_delay = -diff(phase')./(diff(freq)*360);
                    freq1 = freq;
                end
                app.NumberofpointsdisplayedEditField.Value = length(freq1);
                magmax = max(magspec);
                fig4 = uifigure;
                tic; % start stopwatch for plotting graph
                if app.MagnitudespectrumCheckBox.Value == 1
                    fig1 = uifigure; % open up a new figure
                    magPlotAx = uiaxes(fig1, 'Position',[10 10 520 400]);
                    fig4.AutoResizeChildren = 'off';
                    ax1 = subplot(3,1,1,'Parent',fig4);
                    set(ax1, 'Color', 'red')
                    magPlotAx.XScale = 'log';
                    magPlotAx.XLim = [app.minFreq app.maxFreq]; % range of x axis
                    magPlotAx.XGrid = 'on';
                    magPlotAx.YGrid = 'on';
                    magPlotAx.Title.String = 'Magnitude spectrum';
                    magPlotAx.YLabel.String = 'Magnitude (dB)';
                    magPlotAx.XLabel.String = 'Frequency (Hz)';
                    if app.SmoothingSwitch.Value == '1' % filter on
                        if app.FiltertypeDropDown.Value == '1' 
                            plot(magPlotAx, fcenter, magspec - magmax) % plots graph
                            plot(ax1, fcenter, magspec - magmax)
                        else
                            plot(magPlotAx, freq1, magspec - magmax) % plots graph
                            plot(ax1, freq1, magspec - magmax)
                        end
                    else
                        plot(magPlotAx, freq1, magspec - magmax) % plots graph
                        plot(ax1, freq1, magspec - magmax)
                    end
                end
                if app.PhasespectrumunwrappedCheckBox.Value == 1
                    fig2 = uifigure;
                    phasePlotAx = uiaxes(fig2, 'Position',[10 10 520 400]);
                    phasePlotAx.XScale = 'log';
                    phasePlotAx.XLim = [app.minFreq app.maxFreq]; % range of x axis
                    phasePlotAx.XGrid = 'on';
                    phasePlotAx.YGrid = 'on';
                    phasePlotAx.Title.String = 'Phase spectrum';
                    phasePlotAx.YLabel.String = 'Phase (degrees)';
                    phasePlotAx.XLabel.String = 'Frequency (Hz)';
                    plot(phasePlotAx, freq1, phasedeg) % plots graph
                end
                if app.GroupdelayCheckBox.Value == 1
                    fig3 = uifigure;
                    groupPlotAx = uiaxes(fig3, 'Position',[10 10 520 400]);
                    %fig3 = uifigure; % open up a new figure
                    groupPlotAx.XScale = 'log';
                    groupPlotAx.XLim = [app.minFreq app.maxFreq]; % range of x axis
                    groupPlotAx.XGrid = 'on';
                    groupPlotAx.YGrid = 'on';
                    groupPlotAx.Title.String = 'Group delay';
                    groupPlotAx.YLabel.String = 'Group delay (s)';
                    groupPlotAx.XLabel.String = 'Frequency (Hz)';
                    plot(groupPlotAx, freq1(2:end), group_delay) % plots graph
                end
                app.TimetakentoplotgraphsEditField.Value = toc; % time taken
            %catch % if audioread doesn't work
                %app.FilenameEditField.Value = 'Enter a valid file name.';
                % outputted values returned to defaults (0):
                %app.NumberofpointsdisplayedEditField.Value = 0;
                %app.TimetakentoplotgraphsEditField.Value = 0;
                %app.SamplingfrequencyHzEditField.Value = 0;
            %end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Tooltip = {''};
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.Tooltip = {'Enter an audio file name, including the file format at the end, e.g. .wav '};
            app.FilenameEditField.Value = 'task1file.wav';
            app.MinimumfrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumfrequencyHzEditFieldLabel.Text = 'Minimum frequency (Hz)';
            app.MinimumfrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumfrequencyHzEditField.Limits = [0 Inf];
            app.MinimumfrequencyHzEditField.ValueDisplayFormat = '%.0f';
            app.MinimumfrequencyHzEditField.Tooltip = {'Minimum frequency value to be displayed'};
            app.MinimumfrequencyHzEditField.Value = 10;
            app.MaximumfrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumfrequencyHzEditFieldLabel.Text = 'Maximum frequency (Hz)';
            app.MaximumfrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumfrequencyHzEditField.Limits = [0 Inf];
            app.MaximumfrequencyHzEditField.ValueDisplayFormat = '%.0f';
            app.MaximumfrequencyHzEditField.Tooltip = {'Maximum frequency value to be displayed'};
            app.MaximumfrequencyHzEditField.Value = 40000;
            app.SelectfileButton = uibutton(app.UIFigure, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.ORLabel = uilabel(app.UIFigure);
            app.ORLabel.Text = 'OR';
            app.NumberofpointsdisplayedEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofpointsdisplayedEditFieldLabel.Text = 'Number of points displayed';
            app.NumberofpointsdisplayedEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofpointsdisplayedEditField.ValueDisplayFormat = '%.0f';
            app.NumberofpointsdisplayedEditField.Editable = 'off';
            app.TimetakentoplotgraphsEditFieldLabel = uilabel(app.UIFigure);
            app.TimetakentoplotgraphsEditFieldLabel.Text = 'Time taken to plot graph (s)';
            app.TimetakentoplotgraphsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimetakentoplotgraphsEditField.ValueDisplayFormat = '%.3f';
            app.TimetakentoplotgraphsEditField.Editable = 'off';
            app.FrequencydomainsmoothingPanel = uipanel(app.UIFigure);
            app.FrequencydomainsmoothingPanel.Title = 'Frequency domain smoothing';
            app.SmoothingSwitchLabel = uilabel(app.FrequencydomainsmoothingPanel);
            app.SmoothingSwitchLabel.Text = 'Smoothing';
            app.SmoothingSwitch = uiswitch(app.FrequencydomainsmoothingPanel, 'slider');
            app.SmoothingSwitch.ItemsData = {'0', '1'};
            app.SmoothingSwitch.Value = '0';
            app.TabGroup = uitabgroup(app.FrequencydomainsmoothingPanel);
            app.OctaveTab = uitab(app.TabGroup);
            app.OctaveTab.Title = 'Octave';
            app.BaseDropDownLabel = uilabel(app.OctaveTab);
            app.BaseDropDownLabel.Text = 'Base';
            app.BaseDropDown = uidropdown(app.OctaveTab);
            app.BaseDropDown.Items = {'2', '10'};
            app.BaseDropDown.ItemsData = {'1', '2'};
            app.BaseDropDown.Value = '1';
            app.TriangularTab = uitab(app.TabGroup);
            app.TriangularTab.Title = 'Triangular';
            app.DownsamplingDropDownLabel = uilabel(app.TriangularTab);
            app.DownsamplingDropDownLabel.Text = 'Downsampling';
            app.DownsamplingDropDown = uidropdown(app.TriangularTab);
            app.DownsamplingDropDown.Items = {'None', 'Bark Scale'};
            app.DownsamplingDropDown.ItemsData = {'1', '2'};
            app.DownsamplingDropDown.Value = '1';
            app.WindowsizesamplesEditFieldLabel = uilabel(app.TriangularTab);
            app.WindowsizesamplesEditFieldLabel.Text = 'Window size (samples)';
            app.WindowsizesamplesEditField = uieditfield(app.TriangularTab, 'numeric');
            app.WindowsizesamplesEditField.Limits = [0 Inf];
            app.WindowsizesamplesEditField.ValueDisplayFormat = '%.0f';
            app.WindowsizesamplesEditField.Tooltip = {'Minimum frequency value to be displayed'};
            app.WindowsizesamplesEditField.Value = 25;
            app.SGTab = uitab(app.TabGroup);
            app.SGTab.Title = 'SG';
            app.DownsamplingDropDown_2Label = uilabel(app.SGTab);
            app.DownsamplingDropDown_2Label.Text = 'Downsampling';
            app.DownsamplingDropDown_2 = uidropdown(app.SGTab);
            app.DownsamplingDropDown_2.Items = {'None', 'Bark Scale'};
            app.DownsamplingDropDown_2.ItemsData = {'1', '2'};
            app.DownsamplingDropDown_2.Value = '1';
            app.ODDwindowsizesamplesEditFieldLabel = uilabel(app.SGTab);
            app.ODDwindowsizesamplesEditFieldLabel.Text = 'ODD window size (samples)';
            app.ODDwindowsizesamplesEditField = uieditfield(app.SGTab, 'numeric');
            app.ODDwindowsizesamplesEditField.Limits = [0 Inf];
            app.ODDwindowsizesamplesEditField.ValueDisplayFormat = '%.0f';
            app.ODDwindowsizesamplesEditField.Tooltip = {'Minimum frequency value to be displayed'};
            app.ODDwindowsizesamplesEditField.Value = 25;
            app.OrderlessthanwindowsizeEditFieldLabel = uilabel(app.SGTab);
            app.OrderlessthanwindowsizeEditFieldLabel.Text = 'Order (less than window size)';
            app.OrderlessthanwindowsizeEditField = uieditfield(app.SGTab, 'numeric');
            app.OrderlessthanwindowsizeEditField.Limits = [0 Inf];
            app.OrderlessthanwindowsizeEditField.ValueDisplayFormat = '%.0f';
            app.OrderlessthanwindowsizeEditField.Tooltip = {'Minimum frequency value to be displayed'};
            app.OrderlessthanwindowsizeEditField.Value = 9;
            app.FiltertypeDropDownLabel = uilabel(app.FrequencydomainsmoothingPanel);
            app.FiltertypeDropDownLabel.Text = 'Filter type';
            app.FiltertypeDropDown = uidropdown(app.FrequencydomainsmoothingPanel);
            app.FiltertypeDropDown.Items = {'1/3 Octave Smoothing', 'Triangular (T)', 'Savitzky-Golay (SG)'};
            app.FiltertypeDropDown.ItemsData = {'1', '2', '3'};
            app.FiltertypeDropDown.Value = '1';
            app.SamplingfrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingfrequencyHzEditFieldLabel.Text = 'Sampling frequency (Hz)';
            app.SamplingfrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingfrequencyHzEditField.ValueDisplayFormat = '%.0f';
            app.SamplingfrequencyHzEditField.Editable = 'off';
            app.PlottingPanel = uipanel(app.UIFigure);
            app.PlottingPanel.Title = 'Plotting';
            app.MagnitudespectrumCheckBox = uicheckbox(app.PlottingPanel);
            app.MagnitudespectrumCheckBox.Text = 'Magnitude spectrum';
            app.PhasespectrumunwrappedCheckBox = uicheckbox(app.PlottingPanel);
            app.PhasespectrumunwrappedCheckBox.Text = 'Phase spectrum (unwrapped)';
            app.PlotButton = uibutton(app.PlottingPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.GroupdelayCheckBox = uicheckbox(app.PlottingPanel);
            app.GroupdelayCheckBox.Text = 'Group delay';
            app.TimetakentoapplyfiltersEditFieldLabel = uilabel(app.UIFigure);
            app.TimetakentoapplyfiltersEditFieldLabel.Text = 'Time taken to apply filter (s)';
            app.TimetakentoapplyfiltersEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimetakentoapplyfiltersEditField.ValueDisplayFormat = '%.3f';
            app.TimetakentoapplyfiltersEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = task1and2withgui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end