classdef updatedapp_task1to3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        SelectOptionsTab                matlab.ui.container.Tab
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        SelectfileButton                matlab.ui.control.Button
        ORLabel                         matlab.ui.control.Label
        MinimumfrequencyHzEditFieldLabel  matlab.ui.control.Label
        MinimumfrequencyHzEditField     matlab.ui.control.NumericEditField
        MaximumfrequencyHzEditFieldLabel  matlab.ui.control.Label
        MaximumfrequencyHzEditField     matlab.ui.control.NumericEditField
        FrequencydomainsmoothingPanel   matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        OctaveTab                       matlab.ui.container.Tab
        BaseDropDownLabel               matlab.ui.control.Label
        BaseDropDown                    matlab.ui.control.DropDown
        SavitzkyGolayTab                matlab.ui.container.Tab
        DownsamplingDropDown_2Label     matlab.ui.control.Label
        DownsamplingDropDown_2          matlab.ui.control.DropDown
        ODDwindowsizesamplesEditFieldLabel  matlab.ui.control.Label
        ODDwindowsizesamplesEditField   matlab.ui.control.NumericEditField
        OrderlessthanwindowsizeEditFieldLabel  matlab.ui.control.Label
        OrderlessthanwindowsizeEditField  matlab.ui.control.NumericEditField
        UnsmoothedsignalblackCheckBox   matlab.ui.control.CheckBox
        OctaveSmoothblueCheckBox        matlab.ui.control.CheckBox
        RectangularmeangreenCheckBox    matlab.ui.control.CheckBox
        SavitzkyGolayredCheckBox        matlab.ui.control.CheckBox
        magntiudeonlyLabel              matlab.ui.control.Label
        logmeanmagnitudeLabel           matlab.ui.control.Label
        geomeanotherwiseLabel           matlab.ui.control.Label
        PlottingPanel                   matlab.ui.container.Panel
        MagnitudespectrumCheckBox       matlab.ui.control.CheckBox
        PhasespectrumCheckBox           matlab.ui.control.CheckBox
        PlotButton                      matlab.ui.control.Button
        GroupdelayCheckBox              matlab.ui.control.CheckBox
        OpeninnewfiguresCheckBox        matlab.ui.control.CheckBox
        NumberofpointsdisplayedEditFieldLabel  matlab.ui.control.Label
        NumberofpointsdisplayedEditField  matlab.ui.control.NumericEditField
        SamplingfrequencyHzEditFieldLabel  matlab.ui.control.Label
        SamplingfrequencyHzEditField    matlab.ui.control.NumericEditField
        TimetakentoapplyfiltersEditFieldLabel  matlab.ui.control.Label
        TimetakentoapplyfiltersEditField  matlab.ui.control.NumericEditField
        TimetakentoplotgraphsEditFieldLabel  matlab.ui.control.Label
        TimetakentoplotgraphsEditField  matlab.ui.control.NumericEditField
        ViewgraphsTab                   matlab.ui.container.Tab
        magPlotAx                       matlab.ui.control.UIAxes
        phasePlotAx                     matlab.ui.control.UIAxes
        groupPlotAx                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        minFreq = 0 % min freq to be displayed on graph 
        maxFreq = 1 % max freq to be displayed on graph
        samplingFreq
    end
   
    methods (Access = private)
        
        % 1/3 Octave Smoothing
        function [filt_sig, fcenter] = Octave(app, amp, f, base)
            if base == 2
                fcenter  = 10^3 * (2 .^ ([-18:16]/3));
                fd = 2^(1/6);
            else
                fcenter = 10.^(0.1.*[12:46]);
                fd = 10^0.05;
            end
            fupper = fcenter * fd;
            flower = fcenter / fd;
            filt_sig = zeros(length(fcenter), 1);
            for i=1:length(fcenter)
                filt_sig(i) =  10*log10(sum(10.^(0.1*amp(find(f >= flower(i) & f <= fupper(i)))))/length(find(f >= flower(i) & f <= fupper(i)))); % logarithmic mean
            end
        end

        % Bark Downsampling
        function [Bark_freq, Bark_mag, Bark_phase, Bark_group_delay] = BarkDownsample(app, freq, mag, phase, group_delay)
            barkScaleBands = 25; % 24 + 1 for rest of band
            bark_freq = [100 200 300 400 510 630 770 920 1080 1270 1480 1720 2000 2320 2700 3150 3700 4400 5300 6400 7700 9500 12000 15500 48000];
            start_freq_indx = find(round(freq) == 0, 1);
            end_freq_indx = zeros(barkScaleBands, 1);
            for i = 1:barkScaleBands
                endf = find(round(freq) == bark_freq(i), 1);
                end_freq_indx(i) = endf;
            end
            % extra 6 for rest of band
            idealNoPoints = [24 22 20 18 18 18 19 18 17 18 18 17 17 16 15 14 13 12 10 7 4 5 5 6 6];
            
            band_spacing = zeros(barkScaleBands, 1);
            for i = 1:barkScaleBands
                if i == 1
                    band_spacing(i) = round((end_freq_indx(i) - start_freq_indx)/idealNoPoints(i));
                else
                    band_spacing(i) = round((end_freq_indx(i) - end_freq_indx(i - 1))/idealNoPoints(i));
                end
            end
            freq_indx = start_freq_indx:band_spacing(1):(band_spacing(1)*(idealNoPoints(1)-1) + start_freq_indx);
            for n = 2:barkScaleBands
                freq_indx_temp = (end_freq_indx(n - 1)):band_spacing(n):(band_spacing(n)*(idealNoPoints(n) - 1) + end_freq_indx(n - 1));
                freq_indx_temp2 = horzcat(freq_indx, freq_indx_temp);
                if n < barkScaleBands
                    freq_indx = freq_indx_temp2;
                else
                    freq_indx_temp3 = horzcat(freq_indx_temp2, end_freq_indx(n));
                    freq_indx = freq_indx_temp3;
                end
            end
            %Bark_freq = bark_space(app, freq, barkScaleBands, start_freq_indx, end_freq_indx, idealNoPoints, band_spacing);
            Bark_freq = freq(freq_indx);
            Bark_mag = (mag(freq_indx))';
            Bark_phase = (phase(freq_indx))';
            Bark_group_delay1 = group_delay(freq_indx);
            %Bark_mag = bark_space(app, mag', barkScaleBands, start_freq_indx, end_freq_indx, idealNoPoints, band_spacing);
            %Bark_phase = bark_space(app, phase', barkScaleBands, start_freq_indx, end_freq_indx, idealNoPoints, band_spacing);
            %group_delay1 = horzcat(group_delay(1), group_delay);
            %Bark_group_delay1 = bark_space(app, group_delay, barkScaleBands, start_freq_indx, end_freq_indx, idealNoPoints, band_spacing);
            Bark_group_delay = Bark_group_delay1(2:end);
        end
        
        %function new_signal = bark_space(app, signal, no_bands, start_freq_indx, end_freq_indx, idealNoPoints, band_spacing)
            % BARK_SPACE downsamples the signal in accordance with the bark scale
            %new_signal = signal(start_freq_indx:band_spacing(1):(band_spacing(1)*idealNoPoints(1) + start_freq_indx));
            %for n = 2:no_bands
                %new_signal_temp = signal((end_freq_indx(n - 1) + band_spacing(n)):band_spacing(n):(band_spacing(n)*idealNoPoints(n) + end_freq_indx(n - 1)));
                %new_signal_temp2 = horzcat(new_signal, new_signal_temp);
                %new_signal = new_signal_temp2;
            %end
        %end
        
        function [log_means, freq_out] = rlog(app, freq, signal)
            idealNoPoints = [24 22 20 18 18 18 19 18 17 18 18 17 17 16 15 14 13 12 10 7 4 5 5 6 6];
            barkScaleBands = 25; % 24 + 1 for rest of band
            bark_freq = [100 200 300 400 510 630 770 920 1080 1270 1480 1720 2000 2320 2700 3150 3700 4400 5300 6400 7700 9500 12000 15500 48000];
            start_freq_indx = 1;
            end_freq_indx = zeros(barkScaleBands, 1);
            for i = 1:barkScaleBands
                endf = find(round(freq) == bark_freq(i), 1);
                end_freq_indx(i) = endf;
            end
            band_spacing = zeros(barkScaleBands, 1);
            for i = 1:barkScaleBands
                if i == 1
                    band_spacing(i) = round((end_freq_indx(i) - start_freq_indx)/idealNoPoints(i));
                else
                    band_spacing(i) = round((end_freq_indx(i) - end_freq_indx(i - 1))/idealNoPoints(i));
                end
            end
            freq_indx = start_freq_indx:band_spacing(1):(band_spacing(1)*(idealNoPoints(1)-1) + start_freq_indx);
            for n = 2:barkScaleBands
                freq_indx_temp = (end_freq_indx(n - 1)):band_spacing(n):(band_spacing(n)*(idealNoPoints(n) - 1) + end_freq_indx(n - 1));
                freq_indx_temp2 = horzcat(freq_indx, freq_indx_temp);
                if n < barkScaleBands
                    freq_indx = freq_indx_temp2;
                else
                    freq_indx_temp3 = horzcat(freq_indx_temp2, end_freq_indx(n));
                    freq_indx = freq_indx_temp3;
                end
            end
            % first point not averaged 
            %flower = zeros(length(freq_indx) - 1, 1);
            %fupper = zeros(length(freq_indx) - 1, 1);
            avg_spacing = zeros(length(freq_indx) - 1, 1);
            
            avg_spacing_count = 0;
            for i = 1:length(idealNoPoints)
                if i == 1
                    for j = 2:idealNoPoints(i)
                        avg_spacing_count = avg_spacing_count + 1;
                        avg_spacing(avg_spacing_count) = band_spacing(i);
                    end
                else
                    for j = 1:idealNoPoints(i)
                        avg_spacing_count = avg_spacing_count + 1;
                        avg_spacing(avg_spacing_count) = band_spacing(i);
                    end
                end
            end
            log_means = zeros(length(freq_indx), 1);
            log_means(1) = signal(freq_indx(1));
            for n = 2:length(freq_indx)-1
                log_means(n) = 10*log10(sum(10.^(0.1*signal((freq_indx(n) - floor(avg_spacing(n - 1)/2)):(freq_indx(n) + floor(avg_spacing(n - 1)/2)))))/(avg_spacing(n - 1))); % logarithmic mean
            end
            log_means(end) = 10*log10(sum(10.^(0.1*signal((freq_indx(end) - 1000):(freq_indx(end) + 1000))))/2001);
            %log_means(avg_spacing_count) = signal(freq_indx(end));
            freq_out = freq(freq_indx);
            %center_freq_indx = zeros(length(center_freq), 1);
            %flower = zeros(length(center_freq), 1);
            %fupper = zeros(length(center_freq), 1);
            %log_means = zeros(length(center_freq), 1);
            %for n = 1:length(center_freq)
                %center_freq_indx(n) = find(round(freq) == center_freq(n), 1);
                %lower_freq = center_freq(n) - (bandwidths(n)/2);
                %upper_freq = center_freq(n) + (bandwidths(n)/2);
                %flower(n) = find(round(freq) == lower_freq, 1);
                %fupper(n) = find(round(freq) == upper_freq, 1);
                %log_means(n) =  10*log10(sum(10.^(0.1*signal(find(freq >= lower_freq & freq <= upper_freq))))/length(find(freq >= lower_freq & freq <= upper_freq))); % logarithmic mean
            %end
        end
        function [geo_means, freq_out] = rgeo(app, freq, signal)
            idealNoPoints = [24 22 20 18 18 18 19 18 17 18 18 17 17 16 15 14 13 12 10 7 4 5 5 6 6];
            barkScaleBands = 25; % 24 + 1 for rest of band
            bark_freq = [100 200 300 400 510 630 770 920 1080 1270 1480 1720 2000 2320 2700 3150 3700 4400 5300 6400 7700 9500 12000 15500 48000];
            start_freq_indx = 1;
            end_freq_indx = zeros(barkScaleBands, 1);
            for i = 1:barkScaleBands
                endf = find(round(freq) == bark_freq(i), 1);
                end_freq_indx(i) = endf;
            end
            band_spacing = zeros(barkScaleBands, 1);
            for i = 1:barkScaleBands
                if i == 1
                    band_spacing(i) = round((end_freq_indx(i) - start_freq_indx)/idealNoPoints(i));
                else
                    band_spacing(i) = round((end_freq_indx(i) - end_freq_indx(i - 1))/idealNoPoints(i));
                end
            end
            freq_indx = start_freq_indx:band_spacing(1):(band_spacing(1)*(idealNoPoints(1)-1) + start_freq_indx);
            for n = 2:barkScaleBands
                freq_indx_temp = (end_freq_indx(n - 1)):band_spacing(n):(band_spacing(n)*(idealNoPoints(n) - 1) + end_freq_indx(n - 1));
                freq_indx_temp2 = horzcat(freq_indx, freq_indx_temp);
                if n < barkScaleBands
                    freq_indx = freq_indx_temp2;
                else
                    freq_indx_temp3 = horzcat(freq_indx_temp2, end_freq_indx(n));
                    freq_indx = freq_indx_temp3;
                end
            end
            % first point not averaged 
            %flower = zeros(length(freq_indx) - 1, 1);
            %fupper = zeros(length(freq_indx) - 1, 1);
            avg_spacing = zeros(length(freq_indx) - 1, 1);
            
            avg_spacing_count = 0;
            for i = 1:length(idealNoPoints)
                if i == 1
                    for j = 2:idealNoPoints(i)
                        avg_spacing_count = avg_spacing_count + 1;
                        avg_spacing(avg_spacing_count) = band_spacing(i);
                    end
                else
                    for j = 1:idealNoPoints(i)
                        avg_spacing_count = avg_spacing_count + 1;
                        avg_spacing(avg_spacing_count) = band_spacing(i);
                    end
                end
            end
            geo_means = zeros(length(freq_indx), 1);
            geo_means(1) = signal(freq_indx(1));
            for n = 2:length(freq_indx)-1
                geo_means(n) = mean(signal((freq_indx(n) - floor(avg_spacing(n - 1)/2)):(freq_indx(n) + floor(avg_spacing(n - 1)/2))));
            end
            geo_means(end) = 10*log10(sum(10.^(0.1*signal((freq_indx(end) - 1000):(freq_indx(end) + 1000))))/2001);
            %log_means(avg_spacing_count) = signal(freq_indx(end));
            freq_out = freq(freq_indx);
            %center_freq_indx = zeros(length(center_freq), 1);
            %flower = zeros(length(center_freq), 1);
            %fupper = zeros(length(center_freq), 1);
            %log_means = zeros(length(center_freq), 1);
            %for n = 1:length(center_freq)
                %center_freq_indx(n) = find(round(freq) == center_freq(n), 1);
                %lower_freq = center_freq(n) - (bandwidths(n)/2);
                %upper_freq = center_freq(n) + (bandwidths(n)/2);
                %flower(n) = find(round(freq) == lower_freq, 1);
                %fupper(n) = find(round(freq) == upper_freq, 1);
                %log_means(n) =  10*log10(sum(10.^(0.1*signal(find(freq >= lower_freq & freq <= upper_freq))))/length(find(freq >= lower_freq & freq <= upper_freq))); % logarithmic mean
            %end
        end
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            [file,path] = uigetfile('*'); % open file
            if isequal(file,0)
            else
               app.FilenameEditField.Value = fullfile(path, file);
            end
            drawnow; 
            figure(app.UIFigure) % refocus app (otherwise minimised)
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.minFreq = app.MinimumfrequencyHzEditField.Value;
            app.maxFreq = app.MaximumfrequencyHzEditField.Value;
            windowSizeSG = round(abs(app.ODDwindowsizesamplesEditField.Value));
            orderSG = round(abs(app.OrderlessthanwindowsizeEditField.Value));
            if orderSG < 2
                orderSG = 2;
            end
            if windowSizeSG <= orderSG
                windowSizeSG = orderSG + 1;
            end
            if mod(windowSizeSG, 2) == 0
                windowSizeSG = windowSizeSG + 1;
            end
            %try
                [data, fs] = audioread(app.FilenameEditField.Value);
                app.samplingFreq = fs;
                info = audioinfo(app.FilenameEditField.Value);
                NoSamples = info.TotalSamples; % number of samples in file
                %app.NumberofpointsdisplayedEditField.Value = NoSamples;
                app.SamplingfrequencyHzEditField.Value = fs;
                transform = fft(data); % fast fourier transform of signal
                magspec = 20*log10(abs(transform)); % magnitude spectrum
                freq = fs*(0:NoSamples-1)/NoSamples; % frequency axis
                phase = unwrap(angle(transform));
                phasedeg = phase*(180/pi);
                group_delay = -diff(phase')./(diff(freq)*360);
                if app.OctaveSmoothblueCheckBox.Value == 1
                    Nfft = 2^nextpow2(length(data));
                    ydft = fft(data, Nfft);
                    magspec_oct = 20*log10(abs(ydft)); 
                    freq_oct = fs*((0:Nfft-1)/Nfft);
                    phase_oct = unwrap(angle(ydft));
                    %phasedeg_oct = phase_oct*(180/pi);
                    %group_delay_oct = -diff(phase_oct')./(diff(freq_oct)*360);
                    if app.BaseDropDown.Value == '1' % Base 2
                        [filtMag_oct, fcenter] = Octave(app, magspec_oct, freq_oct, 2);
                    else % Base 10
                        [filtMag_oct, fcenter] = Octave(app, magspec_oct, freq_oct, 10);
                    end
                    magspec_oct = filtMag_oct;
                end
                if app.RectangularmeangreenCheckBox.Value == 1
                    [mag_rlog, f_rlog] = rlog(app, freq, magspec);
                    [phasedeg_rlog, f_rlog] = rgeo(app, freq, phasedeg);
                    [group_delay_rlog1, f_rlog] = rgeo(app, freq, group_delay);
                    group_delay_rlog = group_delay_rlog1(2:end);
                end
                if app.SavitzkyGolayredCheckBox.Value == 1
                    if app.DownsamplingDropDown_2.Value == '1' % No downsampling
                        filtMag_sg = sgolayfilt(magspec, orderSG, windowSizeSG);
                        magspec_sg = filtMag_sg;
                        filtPhase_sg = sgolayfilt(phasedeg, orderSG, windowSizeSG);
                        phasedeg_sg = filtPhase_sg;
                        filtGroupDelay_sg = sgolayfilt(group_delay, orderSG, windowSizeSG);
                        group_delay_sg = filtGroupDelay_sg;
                        freq1_sg = freq;
                    elseif app.DownsamplingDropDown_2.Value == '2' % Bark
                        %filtMag_sg1 = sgolayfilt(magspec, 19, 125);
                        [freq1_sg, Bark_mag_sg, Bark_phasedeg_sg, Bark_group_delay_sg] = BarkDownsample(app, freq, magspec, phasedeg, group_delay);
                        filtMag_sg = sgolayfilt(Bark_mag_sg, orderSG, windowSizeSG);
                        magspec_sg = filtMag_sg;
                        filtPhase_sg = sgolayfilt(Bark_phasedeg_sg, orderSG, windowSizeSG);
                        phasedeg_sg = filtPhase_sg;
                        filtGroupDelay_sg = sgolayfilt(Bark_group_delay_sg, orderSG, windowSizeSG);
                        group_delay_sg = filtGroupDelay_sg;
                    end
                end
                cla(app.magPlotAx)
                cla(app.phasePlotAx)
                cla(app.groupPlotAx)
                if app.UnsmoothedsignalblackCheckBox.Value == 1
                    app.NumberofpointsdisplayedEditField.Value = length(freq);
                elseif app.SavitzkyGolayredCheckBox.Value == 1
                    app.NumberofpointsdisplayedEditField.Value = length(freq1_sg);
                elseif app.OctaveSmoothblueCheckBox.Value == 1
                    app.NumberofpointsdisplayedEditField.Value = length(freq_oct);
                elseif app.RectangularmeangreenCheckBox.Value == 1
                    app.NumberofpointsdisplayedEditField.Value = length(f_rlog);
                else
                    app.NumberofpointsdisplayedEditField.Value = 0;
                end
                app.magPlotAx.NextPlot = 'add';
                app.phasePlotAx.NextPlot = 'add';
                app.groupPlotAx.NextPlot = 'add';
                tic; % start stopwatch for plotting graph
                if app.MagnitudespectrumCheckBox.Value == 1
                    magmax = max(magspec);
                    app.magPlotAx.XLim = [app.minFreq app.maxFreq]; % range of x axis
                    if app.OpeninnewfiguresCheckBox.Value == 1
                        magfig = uifigure;
                        magAx = uiaxes(magfig, 'Position',[10 10 520 400]);
                        magAx.XScale = 'log';
                        magAx.XLim = [app.minFreq app.maxFreq]; % range of x axis
                        magAx.XGrid = 'on';
                        magAx.YGrid = 'on';
                        magAx.Title.String = 'Magnitude spectrum';
                        magAx.YLabel.String = 'Magnitude (dB)';
                        magAx.XLabel.String = 'Frequency (Hz)';
                        magAx.NextPlot = 'add';
                    end
                    if app.UnsmoothedsignalblackCheckBox.Value == 1
                        plot(app.magPlotAx, freq, magspec - magmax, 'k')
                        if app.OpeninnewfiguresCheckBox.Value == 1
                            plot(magAx, freq, magspec - magmax, 'k')
                        end
                    end
                    if app.OctaveSmoothblueCheckBox.Value == 1
                        %magmax_oct = max(magspec_oct);
                        plot(app.magPlotAx, fcenter, magspec_oct - magmax, 'b')
                        if app.OpeninnewfiguresCheckBox.Value == 1
                            plot(magAx, fcenter, magspec_oct - magmax, 'b')
                        end
                    end
                    if app.RectangularmeangreenCheckBox.Value == 1
                        %magmax_rlog = max(mag_rlog);
                        plot(app.magPlotAx, f_rlog, mag_rlog - magmax, 'g')
                        if app.OpeninnewfiguresCheckBox.Value == 1
                            plot(magAx, f_rlog, mag_rlog - magmax, 'g')
                        end
                    end
                    if app.SavitzkyGolayredCheckBox.Value == 1
                        %magmax_sg = max(magspec_sg);
                        plot(app.magPlotAx, freq1_sg, magspec_sg - magmax, 'r')
                        if app.OpeninnewfiguresCheckBox.Value == 1
                            plot(magAx, freq1_sg, magspec_sg - magmax, 'r')
                            if app.UnsmoothedsignalblackCheckBox.Value == 1
                                if app.OctaveSmoothblueCheckBox.Value == 1
                                    if app.RectangularmeangreenCheckBox.Value == 1
                                        legend(magAx, 'Unsmoothed', 'Octave', 'Rectangular', 'Savitzky-Golay', 'Location', 'southwest')
                                    else
                                        legend(magAx, 'Unsmoothed', 'Octave', 'Savitzky-Golay', 'Location', 'southwest')
                                    end
                                elseif app.RectangularmeangreenCheckBox.Value == 1
                                    legend(magAx, 'Unsmoothed', 'Rectangular', 'Savitzky-Golay', 'Location', 'southwest')
                                else
                                    legend(magAx, 'Unsmoothed', 'Savitzky-Golay', 'Location', 'southwest')
                                end
                            elseif app.OctaveSmoothblueCheckBox.Value == 1
                                if app.RectangularmeangreenCheckBox.Value == 1
                                    legend(magAx, 'Octave', 'Rectangular', 'Savitzky-Golay', 'Location', 'southwest')
                                else
                                    legend(magAx, 'Octave', 'Savitzky-Golay', 'Location', 'southwest')
                                end
                            elseif app.RectangularmeangreenCheckBox.Value == 1
                                legend(magAx, 'Rectangular', 'Savitzky-Golay', 'Location', 'southwest')
                            else
                                legend(magAx, 'Savitzky-Golay', 'Location', 'southwest')
                            end
                        end
                    elseif (app.UnsmoothedsignalblackCheckBox.Value && app.OpeninnewfiguresCheckBox.Value)
                        if app.OctaveSmoothblueCheckBox.Value == 1
                            if app.RectangularmeangreenCheckBox.Value == 1
                                legend(magAx, 'Unsmoothed', 'Octave', 'Rectangular', 'Location', 'southwest')
                            else
                                legend(magAx, 'Unsmoothed', 'Octave', 'Location', 'southwest')
                            end
                        elseif app.RectangularmeangreenCheckBox.Value == 1
                            legend(magAx, 'Unsmoothed', 'Rectangular', 'Location', 'southwest')
                        else
                            legend(magAx, 'Unsmoothed', 'Location', 'southwest')
                        end
                    elseif (app.OctaveSmoothblueCheckBox.Value && app.OpeninnewfiguresCheckBox.Value)
                        if app.RectangularmeangreenCheckBox.Value == 1
                            legend(magAx, 'Octave', 'Rectangular', 'Location', 'southwest')
                        else
                            legend(magAx, 'Octave', 'Location', 'southwest')
                        end
                    elseif (app.RectangularmeangreenCheckBox.Value == 1 && app.OpeninnewfiguresCheckBox.Value)
                        legend(magAx, 'Rectangular', 'Location', 'southwest')
                    end
                end
                if app.PhasespectrumCheckBox.Value == 1
                    app.phasePlotAx.XLim = [app.minFreq app.maxFreq]; % range of x axis
                    if app.OpeninnewfiguresCheckBox.Value == 1
                        if (app.UnsmoothedsignalblackCheckBox.Value || app.SavitzkyGolayredCheckBox.Value)
                            phasefig = uifigure;
                            phaseAx = uiaxes(phasefig, 'Position',[10 10 520 400]);
                            phaseAx.XScale = 'log';
                            phaseAx.XLim = [app.minFreq app.maxFreq]; % range of x axis
                            phaseAx.XGrid = 'on';
                            phaseAx.YGrid = 'on';
                            phaseAx.Title.String = 'Phase spectrum';
                            phaseAx.YLabel.String = 'Phase (degrees)';
                            phaseAx.XLabel.String = 'Frequency (Hz)';
                            phaseAx.NextPlot = 'add';
                        end
                    end
                    if app.UnsmoothedsignalblackCheckBox.Value == 1
                        plot(app.phasePlotAx, freq, phasedeg, 'k')
                        if app.OpeninnewfiguresCheckBox.Value == 1
                            plot(phaseAx, freq, phasedeg, 'k')
                        end
                    end
                    if app.RectangularmeangreenCheckBox.Value == 1
                        plot(app.phasePlotAx, f_rlog, phasedeg_rlog, 'g')
                        if app.OpeninnewfiguresCheckBox.Value == 1
                            plot(phaseAx, f_rlog, phasedeg_rlog, 'g')
                        end
                    end
                    if app.SavitzkyGolayredCheckBox.Value == 1
                        plot(app.phasePlotAx, freq1_sg, phasedeg_sg, 'r')
                        if app.OpeninnewfiguresCheckBox.Value == 1
                            plot(phaseAx, freq1_sg, phasedeg_sg, 'r')
                            if app.UnsmoothedsignalblackCheckBox.Value == 1
                                if app.RectangularmeangreenCheckBox.Value == 1
                                    legend(phaseAx, 'Unsmoothed', 'Rectangular', 'Savitzky-Golay', 'Location', 'southwest')
                                else
                                    legend(phaseAx, 'Unsmoothed', 'Savitzky-Golay', 'Location', 'southwest')
                                end
                            else
                                if app.RectangularmeangreenCheckBox.Value == 1
                                    legend(phaseAx, 'Rectangular', 'Savitzky-Golay', 'Location', 'southwest')
                                else
                                    legend(phaseAx, 'Savitzky-Golay', 'Location', 'southwest')
                                end
                            end
                        end
                    elseif (app.UnsmoothedsignalblackCheckBox.Value && app.OpeninnewfiguresCheckBox.Value)
                        if app.RectangularmeangreenCheckBox.Value == 1
                            legend(phaseAx, 'Unsmoothed', 'Rectangular', 'Location', 'southwest')
                        else
                            legend(phaseAx, 'Unsmoothed', 'Location', 'southwest')
                        end
                    elseif (app.RectangularmeangreenCheckBox.Value && app.OpeninnewfiguresCheckBox.Value)
                        legend(phaseAx, 'Rectangular', 'Location', 'southwest')
                    end
                end
                if app.GroupdelayCheckBox.Value == 1
                    app.groupPlotAx.XLim = [app.minFreq app.maxFreq]; % range of x axis
                    if app.OpeninnewfiguresCheckBox.Value == 1
                        if (app.UnsmoothedsignalblackCheckBox.Value || app.SavitzkyGolayredCheckBox.Value)
                            groupfig = uifigure;
                            groupAx = uiaxes(groupfig, 'Position',[10 10 520 400]);
                            groupAx.XScale = 'log';
                            groupAx.XLim = [app.minFreq app.maxFreq]; % range of x axis
                            groupAx.XGrid = 'on';
                            groupAx.YGrid = 'on';
                            groupAx.Title.String = 'Group delay';
                            groupAx.YLabel.String = 'Group delay (s)';
                            groupAx.XLabel.String = 'Frequency (Hz)';
                            groupAx.NextPlot = 'add';
                        end
                    end
                    if app.UnsmoothedsignalblackCheckBox.Value == 1
                        plot(app.groupPlotAx, freq(2:end), group_delay, 'k')
                        if app.OpeninnewfiguresCheckBox.Value == 1
                            plot(groupAx, freq(2:end), group_delay, 'k')
                        end
                    end
                    if app.RectangularmeangreenCheckBox.Value == 1
                        plot(app.groupPlotAx, f_rlog(2:end), group_delay_rlog, 'g')
                        if app.OpeninnewfiguresCheckBox.Value == 1
                            plot(groupAx, f_rlog(2:end), group_delay_rlog, 'g')
                        end
                    end
                    if app.SavitzkyGolayredCheckBox.Value == 1
                        plot(app.groupPlotAx, freq1_sg(2:end), group_delay_sg, 'r')
                        if app.OpeninnewfiguresCheckBox.Value == 1
                            plot(groupAx, freq1_sg(2:end), group_delay_sg, 'r')
                            if app.UnsmoothedsignalblackCheckBox.Value == 1
                                if app.RectangularmeangreenCheckBox.Value == 1
                                    legend(groupAx, 'Unsmoothed', 'Rectangular', 'Savitzky-Golay', 'Location', 'southwest')
                                else
                                    legend(groupAx, 'Unsmoothed', 'Savitzky-Golay', 'Location', 'southwest')
                                end
                            else
                                if app.RectangularmeangreenCheckBox.Value == 1
                                    legend(groupAx, 'Rectangular', 'Savitzky-Golay', 'Location', 'southwest')
                                else
                                    legend(groupAx, 'Savitzky-Golay', 'Location', 'southwest')
                                end
                            end
                        end
                    elseif (app.UnsmoothedsignalblackCheckBox.Value && app.OpeninnewfiguresCheckBox.Value)
                        if app.RectangularmeangreenCheckBox.Value == 1
                            legend(groupAx, 'Unsmoothed', 'Rectangular', 'Location', 'southwest')
                        else
                            legend(groupAx, 'Unsmoothed', 'Location', 'southwest')
                        end
                    elseif (app.RectangularmeangreenCheckBox.Value && app.OpeninnewfiguresCheckBox.Value)
                        legend(groupAx, 'Rectangular', 'Location', 'southwest')
                    end
                end
                app.TimetakentoplotgraphsEditField.Value = toc; % time taken
            %catch % if audioread doesn't work
                %app.FilenameEditField.Value = 'Enter a valid file name.';
                % outputted values returned to defaults (0):
                %app.NumberofpointsdisplayedEditField.Value = 0;
                %app.TimetakentoplotgraphsEditField.Value = 0;
                %app.SamplingfrequencyHzEditField.Value = 0;
            %end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.SelectOptionsTab = uitab(app.TabGroup2);
            app.SelectOptionsTab.Title = 'Select Options';
            app.FilenameEditFieldLabel = uilabel(app.SelectOptionsTab);
            app.FilenameEditFieldLabel.Tooltip = {''};
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.SelectOptionsTab, 'text');
            app.FilenameEditField.Tooltip = {'Enter an audio file name, including the file format at the end, e.g. .wav '};
            app.FilenameEditField.Value = 'task1file.wav';
            app.SelectfileButton = uibutton(app.SelectOptionsTab, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.ORLabel = uilabel(app.SelectOptionsTab);
            app.ORLabel.Text = 'OR';
            app.MinimumfrequencyHzEditFieldLabel = uilabel(app.SelectOptionsTab);
            app.MinimumfrequencyHzEditFieldLabel.Text = 'Minimum frequency (Hz)';
            app.MinimumfrequencyHzEditField = uieditfield(app.SelectOptionsTab, 'numeric');
            app.MinimumfrequencyHzEditField.Limits = [0 Inf];
            app.MinimumfrequencyHzEditField.ValueDisplayFormat = '%.0f';
            app.MinimumfrequencyHzEditField.Tooltip = {'Minimum frequency value to be displayed'};
            app.MinimumfrequencyHzEditField.Value = 10;
            app.MaximumfrequencyHzEditFieldLabel = uilabel(app.SelectOptionsTab);
            app.MaximumfrequencyHzEditFieldLabel.Text = 'Maximum frequency (Hz)';
            app.MaximumfrequencyHzEditField = uieditfield(app.SelectOptionsTab, 'numeric');
            app.MaximumfrequencyHzEditField.Limits = [0 Inf];
            app.MaximumfrequencyHzEditField.ValueDisplayFormat = '%.0f';
            app.MaximumfrequencyHzEditField.Tooltip = {'Maximum frequency value to be displayed'};
            app.MaximumfrequencyHzEditField.Value = 40000;
            app.FrequencydomainsmoothingPanel = uipanel(app.SelectOptionsTab);
            app.FrequencydomainsmoothingPanel.Title = 'Frequency domain smoothing';
            app.TabGroup = uitabgroup(app.FrequencydomainsmoothingPanel);
            app.OctaveTab = uitab(app.TabGroup);
            app.OctaveTab.Title = 'Octave';
            app.BaseDropDownLabel = uilabel(app.OctaveTab);
            app.BaseDropDownLabel.Text = 'Base';
            app.BaseDropDown = uidropdown(app.OctaveTab);
            app.BaseDropDown.Items = {'2', '10'};
            app.BaseDropDown.ItemsData = {'1', '2'};
            app.BaseDropDown.Value = '1';
            app.SavitzkyGolayTab = uitab(app.TabGroup);
            app.SavitzkyGolayTab.Title = 'Savitzky-Golay';
            app.DownsamplingDropDown_2Label = uilabel(app.SavitzkyGolayTab);
            app.DownsamplingDropDown_2Label.Text = 'Downsampling';
            app.DownsamplingDropDown_2 = uidropdown(app.SavitzkyGolayTab);
            app.DownsamplingDropDown_2.Items = {'None', 'Bark Scale'};
            app.DownsamplingDropDown_2.ItemsData = {'1', '2', ''};
            app.DownsamplingDropDown_2.Value = '1';
            app.ODDwindowsizesamplesEditFieldLabel = uilabel(app.SavitzkyGolayTab);
            app.ODDwindowsizesamplesEditFieldLabel.Text = 'ODD window size (samples)';
            app.ODDwindowsizesamplesEditField = uieditfield(app.SavitzkyGolayTab, 'numeric');
            app.ODDwindowsizesamplesEditField.Limits = [0 Inf];
            app.ODDwindowsizesamplesEditField.ValueDisplayFormat = '%.0f';
            app.ODDwindowsizesamplesEditField.Tooltip = {'Minimum frequency value to be displayed'};
            app.ODDwindowsizesamplesEditField.Value = 25;
            app.OrderlessthanwindowsizeEditFieldLabel = uilabel(app.SavitzkyGolayTab);
            app.OrderlessthanwindowsizeEditFieldLabel.Text = 'Order (less than window size)';
            app.OrderlessthanwindowsizeEditField = uieditfield(app.SavitzkyGolayTab, 'numeric');
            app.OrderlessthanwindowsizeEditField.Limits = [0 Inf];
            app.OrderlessthanwindowsizeEditField.ValueDisplayFormat = '%.0f';
            app.OrderlessthanwindowsizeEditField.Tooltip = {'Minimum frequency value to be displayed'};
            app.OrderlessthanwindowsizeEditField.Value = 9;
            app.UnsmoothedsignalblackCheckBox = uicheckbox(app.FrequencydomainsmoothingPanel);
            app.UnsmoothedsignalblackCheckBox.Text = 'Unsmoothed signal (black)';
            app.UnsmoothedsignalblackCheckBox.Value = true;
            app.OctaveSmoothblueCheckBox = uicheckbox(app.FrequencydomainsmoothingPanel);
            app.OctaveSmoothblueCheckBox.Text = '1/3 Octave Smooth (blue)';
            app.RectangularmeangreenCheckBox = uicheckbox(app.FrequencydomainsmoothingPanel);
            app.RectangularmeangreenCheckBox.Text = 'Rectangular mean (green)';
            app.SavitzkyGolayredCheckBox = uicheckbox(app.FrequencydomainsmoothingPanel);
            app.SavitzkyGolayredCheckBox.Text = 'Savitzky-Golay (red)';
            app.magntiudeonlyLabel = uilabel(app.FrequencydomainsmoothingPanel);
            app.magntiudeonlyLabel.Text = '(magntiude only)';
            app.logmeanmagnitudeLabel = uilabel(app.FrequencydomainsmoothingPanel);
            app.logmeanmagnitudeLabel.Text = '(log-mean magnitude)';
            app.geomeanotherwiseLabel = uilabel(app.FrequencydomainsmoothingPanel);
            app.geomeanotherwiseLabel.Text = '(geo-mean otherwise)';
            app.PlottingPanel = uipanel(app.SelectOptionsTab);
            app.PlottingPanel.Title = 'Plotting';
            app.MagnitudespectrumCheckBox = uicheckbox(app.PlottingPanel);
            app.MagnitudespectrumCheckBox.Text = 'Magnitude spectrum';
            app.MagnitudespectrumCheckBox.Value = true;
            app.PhasespectrumCheckBox = uicheckbox(app.PlottingPanel);
            app.PhasespectrumCheckBox.Text = 'Phase spectrum';
            app.PhasespectrumCheckBox.Value = true;
            app.PlotButton = uibutton(app.PlottingPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.GroupdelayCheckBox = uicheckbox(app.PlottingPanel);
            app.GroupdelayCheckBox.Text = 'Group delay';
            app.GroupdelayCheckBox.Value = true;
            app.OpeninnewfiguresCheckBox = uicheckbox(app.PlottingPanel);
            app.OpeninnewfiguresCheckBox.Text = 'Open in new figures';
            app.NumberofpointsdisplayedEditFieldLabel = uilabel(app.SelectOptionsTab);
            app.NumberofpointsdisplayedEditFieldLabel.Text = 'Number of points displayed';
            app.NumberofpointsdisplayedEditField = uieditfield(app.SelectOptionsTab, 'numeric');
            app.NumberofpointsdisplayedEditField.ValueDisplayFormat = '%.0f';
            app.NumberofpointsdisplayedEditField.Editable = 'off';
            app.SamplingfrequencyHzEditFieldLabel = uilabel(app.SelectOptionsTab);
            app.SamplingfrequencyHzEditFieldLabel.Text = 'Sampling frequency (Hz)';
            app.SamplingfrequencyHzEditField = uieditfield(app.SelectOptionsTab, 'numeric');
            app.SamplingfrequencyHzEditField.ValueDisplayFormat = '%.0f';
            app.SamplingfrequencyHzEditField.Editable = 'off';
            app.TimetakentoapplyfiltersEditFieldLabel = uilabel(app.SelectOptionsTab);
            app.TimetakentoapplyfiltersEditFieldLabel.Text = 'Time taken to apply filter (s)';
            app.TimetakentoapplyfiltersEditField = uieditfield(app.SelectOptionsTab, 'numeric');
            app.TimetakentoapplyfiltersEditField.ValueDisplayFormat = '%.3f';
            app.TimetakentoapplyfiltersEditField.Editable = 'off';
            app.TimetakentoplotgraphsEditFieldLabel = uilabel(app.SelectOptionsTab);
            app.TimetakentoplotgraphsEditFieldLabel.Text = 'Time taken to plot graph (s)';
            app.TimetakentoplotgraphsEditField = uieditfield(app.SelectOptionsTab, 'numeric');
            app.TimetakentoplotgraphsEditField.ValueDisplayFormat = '%.3f';
            app.TimetakentoplotgraphsEditField.Editable = 'off';
            app.ViewgraphsTab = uitab(app.TabGroup2);
            app.ViewgraphsTab.Title = 'View graphs';
            app.magPlotAx = uiaxes(app.ViewgraphsTab);
            title(app.magPlotAx, 'Magnitude')
            xlabel(app.magPlotAx, 'frequency (Hz)')
            ylabel(app.magPlotAx, 'Magnitude (dB)')
            app.magPlotAx.XLim = [10 40000];
            app.magPlotAx.YLim = [-150 0];
            app.magPlotAx.XScale = 'log';
            app.phasePlotAx = uiaxes(app.ViewgraphsTab);
            title(app.phasePlotAx, 'Phase')
            xlabel(app.phasePlotAx, 'frequency (Hz)')
            ylabel(app.phasePlotAx, 'Phase (deg)')
            app.phasePlotAx.XLim = [10 40000];
            app.phasePlotAx.XScale = 'log';
            app.groupPlotAx = uiaxes(app.ViewgraphsTab);
            title(app.groupPlotAx, 'Group Delay')
            xlabel(app.groupPlotAx, 'frequency (Hz)')
            ylabel(app.groupPlotAx, 'Group delay (s)')
            app.groupPlotAx.XLim = [10 40000];
            app.groupPlotAx.XScale = 'log';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = updatedapp_task1to3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end